classdef CubeSat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CubeSatThermalPowerToolboxUIFigure  matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        SaveButton                     matlab.ui.container.toolbar.PushTool
        OpenFolderButton               matlab.ui.container.toolbar.PushTool
        TabGroup2                      matlab.ui.container.TabGroup
        OrbitTab                       matlab.ui.container.Tab
        UpdateOrbitStatusLabel         matlab.ui.control.Label
        UpdateOrbitButton              matlab.ui.control.Button
        CheckBoxShowSunVector          matlab.ui.control.CheckBox
        PropagatorDropDown             matlab.ui.control.DropDown
        PropagatorDropDownLabel        matlab.ui.control.Label
        AlbedoEditField                matlab.ui.control.NumericEditField
        AlbedoEditFieldLabel           matlab.ui.control.Label
        NightTempKEditField            matlab.ui.control.NumericEditField
        NightTempKEditFieldLabel       matlab.ui.control.Label
        DayTempKEditField              matlab.ui.control.NumericEditField
        DayTempKEditFieldLabel         matlab.ui.control.Label
        IRmodelDropDown                matlab.ui.control.DropDown
        IRmodelDropDownLabel           matlab.ui.control.Label
        SolarfluxWm2EditField          matlab.ui.control.NumericEditField
        SolarfluxWm2EditFieldLabel     matlab.ui.control.Label
        TrueAnomalyEditField           matlab.ui.control.NumericEditField
        TrueAnomalyEditFieldLabel      matlab.ui.control.Label
        ArgumentofPeriapsisEditField   matlab.ui.control.NumericEditField
        ArgumentofPeriapsisEditFieldLabel  matlab.ui.control.Label
        RAANEditField                  matlab.ui.control.NumericEditField
        RAANEditFieldLabel             matlab.ui.control.Label
        InclinationEditField           matlab.ui.control.NumericEditField
        InclinationEditFieldLabel      matlab.ui.control.Label
        EccentricityEditField          matlab.ui.control.NumericEditField
        EccentricityEditFieldLabel     matlab.ui.control.Label
        SemimajoraxiskmEditField       matlab.ui.control.NumericEditField
        SemimajoraxiskmEditFieldLabel  matlab.ui.control.Label
        DSpinnerLabel                  matlab.ui.control.Label
        DurationhoursSpinner           matlab.ui.control.Spinner
        DurationhoursSpinnerLabel      matlab.ui.control.Label
        MSpinner                       matlab.ui.control.Spinner
        MSpinnerLabel                  matlab.ui.control.Label
        HSpinner                       matlab.ui.control.Spinner
        HSpinnerLabel                  matlab.ui.control.Label
        EpochDatePicker                matlab.ui.control.DatePicker
        EpochDatePickerLabel           matlab.ui.control.Label
        CentralBodyDropDown            matlab.ui.control.DropDown
        CentralBodyDropDownLabel       matlab.ui.control.Label
        UIAxesBetaAngle                matlab.ui.control.UIAxes
        UIAxesAltitude                 matlab.ui.control.UIAxes
        UIAxesSunLight                 matlab.ui.control.UIAxes
        UIAxesOrbit3D                  matlab.ui.control.UIAxes
        ModelTab                       matlab.ui.container.Tab
        RamFaceDropDown                matlab.ui.control.DropDown
        RamFaceDropDownLabel           matlab.ui.control.Label
        NadirFaceDropDown              matlab.ui.control.DropDown
        NadirFacegreenLabel            matlab.ui.control.Label
        BodyMasskgSpinner              matlab.ui.control.Spinner
        BodyMasskgLabel                matlab.ui.control.Label
        UnitsDropDown                  matlab.ui.control.DropDown
        UnitsDropDownLabel             matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        XplusTab                       matlab.ui.container.Tab
        DeployableSolarPanelXplus      matlab.ui.container.Panel
        FlipPanelCheckBoxXplus         matlab.ui.control.CheckBox
        SizeDropDownXplus              matlab.ui.control.DropDown
        SizeDropDownLabel              matlab.ui.control.Label
        EffecAreaRatioSpinnerXplusDeployable  matlab.ui.control.Spinner
        EffecAreaRatioSpinnerLabelXplus_2  matlab.ui.control.Label
        CellEfficiencySpinnerXplusDeployable  matlab.ui.control.Spinner
        CellEfficiencySpinnerLabelXplusDeployable  matlab.ui.control.Label
        HingeDropDownXplus             matlab.ui.control.DropDown
        HingeDropDownLabel             matlab.ui.control.Label
        PanelTypeButtonGroupXplus      matlab.ui.container.ButtonGroup
        TrackingButtonXplus            matlab.ui.control.RadioButton
        FixedButtonXplus               matlab.ui.control.RadioButton
        NoneButtonXplus                matlab.ui.control.RadioButton
        CpSpinnerXplus                 matlab.ui.control.Spinner
        AbsorptivityalphaLabel_2       matlab.ui.control.Label
        RatioEditFieldXplus            matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        EmissivitySpinnerXplus         matlab.ui.control.Spinner
        EmissivityLabel                matlab.ui.control.Label
        AbsorptivitySpinnerXplus       matlab.ui.control.Spinner
        AbsorptivityalphaLabel         matlab.ui.control.Label
        SurfaceTypeDropDownXplus       matlab.ui.control.DropDown
        SurfaceTypeDropDownLabel       matlab.ui.control.Label
        EffecAreaRatioSpinnerXplus     matlab.ui.control.Spinner
        EffecAreaRatioSpinnerLabelXplus  matlab.ui.control.Label
        CellEfficiencySpinnerXplus     matlab.ui.control.Spinner
        CellEfficiencySpinnerLabelXplus  matlab.ui.control.Label
        XminusTab                      matlab.ui.container.Tab
        DeployableSolarPanelXminus     matlab.ui.container.Panel
        FlipPanelCheckBoxXminus        matlab.ui.control.CheckBox
        SizeDropDownXminus             matlab.ui.control.DropDown
        SizeDropDownLabelXminus        matlab.ui.control.Label
        EffecAreaRatioSpinnerXminusDeployable  matlab.ui.control.Spinner
        EffecAreaRatioSpinnerLabelXplus_3  matlab.ui.control.Label
        CellEfficiencySpinnerXminusDeployable  matlab.ui.control.Spinner
        CellEfficiencySpinnerLabelXminusDeployable  matlab.ui.control.Label
        HingeDropDownXminus            matlab.ui.control.DropDown
        HingeDropDownLabel_2           matlab.ui.control.Label
        PanelTypeButtonGroupXminus     matlab.ui.container.ButtonGroup
        TrackingButtonXminus           matlab.ui.control.RadioButton
        FixedButtonXminus              matlab.ui.control.RadioButton
        NoneButtonXminus               matlab.ui.control.RadioButton
        SurfaceTypeDropDownXminus      matlab.ui.control.DropDown
        SurfaceTypeDropDownLabel_2     matlab.ui.control.Label
        CpSpinnerXminus                matlab.ui.control.Spinner
        AbsorptivityalphaLabel_4       matlab.ui.control.Label
        AbsorptivitySpinnerXminus      matlab.ui.control.Spinner
        AbsorptivityalphaLabel_3       matlab.ui.control.Label
        EmissivitySpinnerXminus        matlab.ui.control.Spinner
        EmissivityLabel_2              matlab.ui.control.Label
        RatioEditFieldXminus           matlab.ui.control.NumericEditField
        Label_2                        matlab.ui.control.Label
        EffecAreaRatioSpinnerXminus    matlab.ui.control.Spinner
        EffecAreaRatioSpinnerLabelXminus  matlab.ui.control.Label
        CellEfficiencySpinnerXminus    matlab.ui.control.Spinner
        CellEfficiencySpinnerLabelXminus  matlab.ui.control.Label
        YplusTab                       matlab.ui.container.Tab
        DeployableSolarPanelYplus      matlab.ui.container.Panel
        FlipPanelCheckBoxYplus         matlab.ui.control.CheckBox
        SizeDropDownYplus              matlab.ui.control.DropDown
        SizeDropDownLabel_3            matlab.ui.control.Label
        EffecAreaRatioSpinnerYplusDeployable  matlab.ui.control.Spinner
        EffecAreaRatioSpinnerLabelXplus_4  matlab.ui.control.Label
        CellEfficiencySpinnerYplusDeployable  matlab.ui.control.Spinner
        CellEfficiencySpinnerLabelYplusDeployable  matlab.ui.control.Label
        HingeDropDownYplus             matlab.ui.control.DropDown
        HingeDropDownLabel_3           matlab.ui.control.Label
        PanelTypeButtonGroupYplus      matlab.ui.container.ButtonGroup
        TrackingButtonYplus            matlab.ui.control.RadioButton
        FixedButtonYplus               matlab.ui.control.RadioButton
        NoneButtonYplus                matlab.ui.control.RadioButton
        SurfaceTypeDropDownYplus       matlab.ui.control.DropDown
        SurfaceTypeDropDownLabel_3     matlab.ui.control.Label
        CpSpinnerYplus                 matlab.ui.control.Spinner
        AbsorptivityalphaLabel_6       matlab.ui.control.Label
        AbsorptivitySpinnerYplus       matlab.ui.control.Spinner
        AbsorptivityalphaLabel_5       matlab.ui.control.Label
        EmissivitySpinnerYplus         matlab.ui.control.Spinner
        EmissivityLabel_3              matlab.ui.control.Label
        RatioEditFieldYplus            matlab.ui.control.NumericEditField
        Label_3                        matlab.ui.control.Label
        EffecAreaRatioSpinnerYplus     matlab.ui.control.Spinner
        EffecAreaRatioSpinnerLabelYplus  matlab.ui.control.Label
        CellEfficiencySpinnerYplus     matlab.ui.control.Spinner
        CellEfficiencySpinnerLabelYplus  matlab.ui.control.Label
        YminusTab                      matlab.ui.container.Tab
        DeployableSolarPanelYminus     matlab.ui.container.Panel
        FlipPanelCheckBoxYminus        matlab.ui.control.CheckBox
        SizeDropDownYminus             matlab.ui.control.DropDown
        SizeDropDownLabel_4            matlab.ui.control.Label
        EffecAreaRatioSpinnerYminusDeployable  matlab.ui.control.Spinner
        EffecAreaRatioSpinnerLabelXplus_5  matlab.ui.control.Label
        CellEfficiencySpinnerYminusDeployable  matlab.ui.control.Spinner
        CellEfficiencySpinnerLabelYminusDeployable  matlab.ui.control.Label
        HingeDropDownYminus            matlab.ui.control.DropDown
        HingeDropDownLabel_4           matlab.ui.control.Label
        PanelTypeButtonGroupYminus     matlab.ui.container.ButtonGroup
        TrackingButtonYminus           matlab.ui.control.RadioButton
        FixedButtonYminus              matlab.ui.control.RadioButton
        NoneButtonYminus               matlab.ui.control.RadioButton
        SurfaceTypeDropDownYminus      matlab.ui.control.DropDown
        SurfaceTypeDropDownLabel_4     matlab.ui.control.Label
        CpSpinnerYminus                matlab.ui.control.Spinner
        AbsorptivityalphaLabel_8       matlab.ui.control.Label
        AbsorptivitySpinnerYminus      matlab.ui.control.Spinner
        AbsorptivityalphaLabel_7       matlab.ui.control.Label
        EmissivitySpinnerYminus        matlab.ui.control.Spinner
        EmissivityLabel_4              matlab.ui.control.Label
        RatioEditFieldYminus           matlab.ui.control.NumericEditField
        Label_4                        matlab.ui.control.Label
        EffecAreaRatioSpinnerYminus    matlab.ui.control.Spinner
        EffecAreaRatioSpinnerLabelYminus  matlab.ui.control.Label
        CellEfficiencySpinnerYminus    matlab.ui.control.Spinner
        CellEfficiencySpinnerLabelYminus  matlab.ui.control.Label
        ZplusTab                       matlab.ui.container.Tab
        DeployableSolarPanelZplus      matlab.ui.container.Panel
        FlipPanelCheckBoxZplus         matlab.ui.control.CheckBox
        SizeDropDownZplus              matlab.ui.control.DropDown
        SizeDropDownLabel_5            matlab.ui.control.Label
        EffecAreaRatioSpinnerZplusDeployable  matlab.ui.control.Spinner
        EffecAreaRatioSpinnerLabelZplus2  matlab.ui.control.Label
        CellEfficiencySpinnerZplusDeployable  matlab.ui.control.Spinner
        CellEfficiencySpinnerLabelZplusDeployable  matlab.ui.control.Label
        HingeDropDownZplus             matlab.ui.control.DropDown
        HingeDropDownLabelZplus2       matlab.ui.control.Label
        PanelTypeButtonGroupZplus      matlab.ui.container.ButtonGroup
        TrackingButtonZplus            matlab.ui.control.RadioButton
        FixedButtonZplus               matlab.ui.control.RadioButton
        NoneButtonZplus                matlab.ui.control.RadioButton
        SurfaceTypeDropDownZplus       matlab.ui.control.DropDown
        SurfaceTypeDropDownLabel_5     matlab.ui.control.Label
        CpSpinnerZplus                 matlab.ui.control.Spinner
        AbsorptivityalphaLabel_10      matlab.ui.control.Label
        AbsorptivitySpinnerZplus       matlab.ui.control.Spinner
        AbsorptivityalphaLabel_9       matlab.ui.control.Label
        EmissivitySpinnerZplus         matlab.ui.control.Spinner
        EmissivityLabel_5              matlab.ui.control.Label
        RatioEditFieldZplus            matlab.ui.control.NumericEditField
        Label_5                        matlab.ui.control.Label
        EffecAreaRatioSpinnerZplus     matlab.ui.control.Spinner
        EffecAreaRatioSpinnerLabelZplus  matlab.ui.control.Label
        CellEfficiencySpinnerZplus     matlab.ui.control.Spinner
        CellEfficiencySpinnerLabelZplus  matlab.ui.control.Label
        ZminusTab                      matlab.ui.container.Tab
        DeployableSolarPanelZminus     matlab.ui.container.Panel
        FlipPanelCheckBoxZminus        matlab.ui.control.CheckBox
        SizeDropDownZminus             matlab.ui.control.DropDown
        SizeDropDownLabelZminus2       matlab.ui.control.Label
        EffecAreaRatioSpinnerZminusDeployable  matlab.ui.control.Spinner
        EffecAreaRatioSpinnerLabelZminus2  matlab.ui.control.Label
        CellEfficiencySpinnerZminusDeployable  matlab.ui.control.Spinner
        CellEfficiencySpinnerLabelZminusDeployable  matlab.ui.control.Label
        HingeDropDownZminus            matlab.ui.control.DropDown
        HingeDropDownLabelZminus2      matlab.ui.control.Label
        PanelTypeButtonGroupZminus     matlab.ui.container.ButtonGroup
        TrackingButtonZminus           matlab.ui.control.RadioButton
        FixedButtonZminus              matlab.ui.control.RadioButton
        NoneButtonZminus               matlab.ui.control.RadioButton
        SurfaceTypeDropDownZminus      matlab.ui.control.DropDown
        SurfaceTypeDropDownLabel_6     matlab.ui.control.Label
        CpSpinnerZminus                matlab.ui.control.Spinner
        AbsorptivityalphaLabel_12      matlab.ui.control.Label
        AbsorptivitySpinnerZminus      matlab.ui.control.Spinner
        AbsorptivityalphaLabel_11      matlab.ui.control.Label
        EmissivitySpinnerZminus        matlab.ui.control.Spinner
        EmissivityLabel_6              matlab.ui.control.Label
        RatioEditFieldZminus           matlab.ui.control.NumericEditField
        Label_6                        matlab.ui.control.Label
        EffecAreaRatioSpinnerZminus    matlab.ui.control.Spinner
        EffecAreaRatioSpinnerLabelZminus  matlab.ui.control.Label
        CellEfficiencySpinnerZminus    matlab.ui.control.Spinner
        CellEfficiencySpinnerLabelZminus  matlab.ui.control.Label
        ThermalParametersPanel         matlab.ui.container.Panel
        UITableMassDistribution        matlab.ui.control.Table
        ThermalResistancesUnitKWLabel  matlab.ui.control.Label
        IntermalnodeSpecificHeatJkgKSpinner  matlab.ui.control.Spinner
        IntermalnodeSpecificHeatJkgKSpinnerLabel  matlab.ui.control.Label
        IntermalnodetototalmassratioSpinner  matlab.ui.control.Spinner
        IntermalnodetototalmassratioSpinnerLabel  matlab.ui.control.Label
        ThermalResistanceImage         matlab.ui.control.Image
        SpinnerRZminus                 matlab.ui.control.Spinner
        RXLabel_6                      matlab.ui.control.Label
        SpinnerRZplus                  matlab.ui.control.Spinner
        RXLabel_5                      matlab.ui.control.Label
        SpinnerRYminus                 matlab.ui.control.Spinner
        RXLabel_4                      matlab.ui.control.Label
        SpinnerRYplus                  matlab.ui.control.Spinner
        RXLabel_3                      matlab.ui.control.Label
        SpinnerRXminus                 matlab.ui.control.Spinner
        RXLabel_2                      matlab.ui.control.Label
        RXLabel                        matlab.ui.control.Label
        SpinnerRXplus                  matlab.ui.control.Spinner
        UIAxesCubeSat                  matlab.ui.control.UIAxes
        PowerTab                       matlab.ui.container.Tab
        PlayButton                     matlab.ui.control.StateButton
        ConsidershadowsCheckBox        matlab.ui.control.CheckBox
        SliderTimeLine                 matlab.ui.control.Slider
        TimeLineLabel                  matlab.ui.control.Label
        ComputePowerStatusLabel        matlab.ui.control.Label
        ComputePowerButton             matlab.ui.control.Button
        InternalPowerDissipationLabel  matlab.ui.control.Label
        TabGroup3                      matlab.ui.container.TabGroup
        Node1Tab                       matlab.ui.container.Tab
        RefreshButton                  matlab.ui.control.Button
        PwrNightWSpinner               matlab.ui.control.Spinner
        PwrNightWSpinnerLabel          matlab.ui.control.Label
        ProfileDropDown                matlab.ui.control.DropDown
        ProfileDropDownLabel           matlab.ui.control.Label
        PwrDayWSpinner                 matlab.ui.control.Spinner
        PwrDayWSpinnerLabel            matlab.ui.control.Label
        UIAxesCubeSatPowerView         matlab.ui.control.UIAxes
        UIAxesBatteryDepletion         matlab.ui.control.UIAxes
        UIAxesPwrGeneration            matlab.ui.control.UIAxes
        UIAxesPwrConsumption           matlab.ui.control.UIAxes
        ThermalTab                     matlab.ui.container.Tab
        IRplotOptionsDropDown          matlab.ui.control.DropDown
        ExportResultsButton            matlab.ui.control.Button
        FacesCheckBoxAlbedoRadiation   matlab.ui.control.CheckBox
        FacesCheckBoxIRradiation       matlab.ui.control.CheckBox
        FacesCheckBoxSolarRadiation    matlab.ui.control.CheckBox
        ComputeThermalStatusLabel      matlab.ui.control.Label
        ComputeThermalButton           matlab.ui.control.Button
        UIAxesFacesTemp                matlab.ui.control.UIAxes
        UIAxesAlbedoInput              matlab.ui.control.UIAxes
        UIAxesIRinput                  matlab.ui.control.UIAxes
        UIAxesSolarRadiation           matlab.ui.control.UIAxes
        UIAxesInternalNodesTemp        matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
    end

    
    properties (Access = public)
        param % Store all simulation parameters

    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          

        % ## Resize Screen to Adjust to User's Display ##   
%         screenSize = get(groot,'ScreenSize');
%         screenWidth = screenSize(3);
%         screenHeight = screenSize(4);
%         left = screenWidth*0.1;
%         bottom = screenHeight*0.1;
%         width = screenWidth*0.8;
%         height = screenHeight*0.8;
%         drawnow;
%         app.CubeSatThermalPowerToolboxUIFigure.Position = [left bottom width height]; 
%             
            
            
            run("initializeParameters.m")
            plotOrbit3D(app.UIAxesOrbit3D, app.param.orb.centralBody);
            plotCubesatView(app.param, app.UIAxesCubeSat);
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            value = app.UnitsDropDown.Value;
            numberOfUnits = str2num(extractBefore(value,"U"));
            app.param.numberOfUnits = numberOfUnits;
            plotCubesatView(app.param, app.UIAxesCubeSat);
            unitArea = 0.1*0.1; % Unit area (m2)
            switch numberOfUnits
                case 1
                    num1UFaces = 6;
                    facesMassDistribution = [1/6, 1/6, 1/6, 1/6, 1/6, 1/6];
                    app.param.facesArea = facesMassDistribution*(6*unitArea);
                    % Update satellite mass (~1.33 kg per Unit)
                    app.param.satelliteMass = 1;          
                case 3
                    num1UFaces = 14;
                    facesMassDistribution = [1/14, 1/14, 3/14, 3/14, 3/14, 3/14]; 
                    app.param.facesArea = facesMassDistribution*(14*unitArea);
                    % Update satellite mass (~1.33 kg per Unit)
                    app.param.satelliteMass = 4;
                case 6
                    num1UFaces = 22;
                    facesMassDistribution = [2/22, 2/22, 3/22, 3/22, 6/22, 6/22];
                    app.param.facesArea = facesMassDistribution*(22*unitArea);
                    % Update satellite mass (~1.33 kg per Unit)
                    app.param.satelliteMass = 8;
                case 8
                    num1UFaces = 28;
                    facesMassDistribution = [2/28, 2/28, 4/28, 4/28, 8/28, 8/28];
                    app.param.facesArea = facesMassDistribution*(28*unitArea);
                    % Update satellite mass (~1.33 kg per Unit)
                    app.param.satelliteMass = 11;
                case 12
                    num1UFaces = 32;
                    facesMassDistribution = [4/32, 4/32, 6/32, 6/32, 6/32, 6/32];
                    app.param.facesArea = facesMassDistribution*(32*unitArea);
                    % Update satellite mass (~1.33 kg per Unit)
                    app.param.satelliteMass = 16;
                case 16
                    num1UFaces = 40;
                    facesMassDistribution = [4/40, 4/40, 8/40, 8/40, 8/40, 8/40];
                    app.param.facesArea = facesMassDistribution*(40*unitArea);
                    % Update satellite mass (~1.33 kg per Unit)
                    app.param.satelliteMass = 22;
            end

            app.param.facesMassDistribution = facesMassDistribution; %[X+,X-,Y+,Y-,Z+, Z-]
            app.BodyMasskgSpinner.Value = app.param.satelliteMass;

            % Update thermal resistances
            facesUnits = facesMassDistribution * num1UFaces;
            app.param.resistance = app.param.conductionResistance1U ./ facesUnits;
            app.SpinnerRXplus.Value = app.param.resistance(1);
            app.SpinnerRXminus.Value = app.param.resistance(2);
            app.SpinnerRYplus.Value = app.param.resistance(3);
            app.SpinnerRYminus.Value = app.param.resistance(4);
            app.SpinnerRZplus.Value = app.param.resistance(5);
            app.SpinnerRZminus.Value = app.param.resistance(6);

            % Update Mass Table
            massVector = app.param.satelliteMass * [app.param.internalNode2TotalMassRatio; (1-app.param.internalNode2TotalMassRatio)* app.param.facesMassDistribution'];
            app.UITableMassDistribution.Data = table(["Internal Node";"X+";"X-";"Y+";"Y-";"Z+";"Z-"],massVector,'VariableNames',{'Node','Mass'});

        end

        % Callback function
        function CoatingButtonXplusSelectionChanged(app, event)
            selectedButton = app.CoatingButtonXplus.SelectedObject;
            switch selectedButton.Text
                case "Gold"
                    app.param.facesMaterial(1) = "goldCoating";
                    app.CellEfficiencySpinnerXplus.Visible = "off";
                    app.CellEfficiencySpinnerLabelXplus.Visible = "off";
                    app.EffecAreaRatioSpinnerXplus.Visible = "off";
                    app.EffecAreaRatioSpinnerLabelXplus.Visible = "off";
                case "Black"
                    app.param.facesMaterial(1) = "blackCoating";
                    app.CellEfficiencySpinnerXplus.Visible = "off";
                    app.CellEfficiencySpinnerLabelXplus.Visible = "off";
                    app.EffecAreaRatioSpinnerXplus.Visible = "off";
                    app.EffecAreaRatioSpinnerLabelXplus.Visible = "off";
                case "White"
                    app.param.facesMaterial(1) = "whiteCoating";
                    app.CellEfficiencySpinnerXplus.Visible = "off";
                    app.CellEfficiencySpinnerLabelXplus.Visible = "off";
                    app.EffecAreaRatioSpinnerXplus.Visible = "off";
                    app.EffecAreaRatioSpinnerLabelXplus.Visible = "off";
                case "Solar Panel"
                    app.param.facesMaterial(1) = "solarPanel";
                    app.CellEfficiencySpinnerXplus.Visible = "on";
                    app.CellEfficiencySpinnerLabelXplus.Visible = "on";
                    app.EffecAreaRatioSpinnerXplus.Visible = "on";
                    app.EffecAreaRatioSpinnerLabelXplus.Visible = "on";
            end
            plotCubesatView(app.param, app.UIAxesCubeSat);
        end

        % Callback function
        function CoatingButtonXminusSelectionChanged(app, event)
            selectedButton = app.CoatingButtonXminus.SelectedObject;
            switch selectedButton.Text
                case "Gold"
                    app.param.facesMaterial(2) = "goldCoating";
                    app.CellEfficiencySpinnerXminus.Visible = "off";
                    app.CellEfficiencySpinnerLabelXminus.Visible = "off";
                    app.EffecAreaRatioSpinnerXminus.Visible = "off";
                    app.EffecAreaRatioSpinnerLabelXminus.Visible = "off";
                case "Black"
                    app.param.facesMaterial(2) = "blackCoating";
                    app.CellEfficiencySpinnerXminus.Visible = "off";
                    app.CellEfficiencySpinnerLabelXminus.Visible = "off";
                    app.EffecAreaRatioSpinnerXminus.Visible = "off";
                    app.EffecAreaRatioSpinnerLabelXminus.Visible = "off";
                case "White"
                    app.param.facesMaterial(2) = "whiteCoating";
                    app.CellEfficiencySpinnerXminus.Visible = "off";
                    app.CellEfficiencySpinnerLabelXminus.Visible = "off";
                    app.EffecAreaRatioSpinnerXminus.Visible = "off";
                    app.EffecAreaRatioSpinnerLabelXminus.Visible = "off";
                case "Solar Panel"
                    app.param.facesMaterial(2) = "solarPanel";
                    app.CellEfficiencySpinnerXminus.Visible = "on";
                    app.CellEfficiencySpinnerLabelXminus.Visible = "on";
                    app.EffecAreaRatioSpinnerXminus.Visible = "on";
                    app.EffecAreaRatioSpinnerLabelXminus.Visible = "on";
            end
            plotCubesatView(app.param, app.UIAxesCubeSat);
            
        end

        % Callback function
        function CoatingButtonYplusSelectionChanged(app, event)
            selectedButton = app.CoatingButtonYplus.SelectedObject;
            switch selectedButton.Text
                case "Gold"
                    app.param.facesMaterial(3) = "goldCoating";
                    app.CellEfficiencySpinnerYplus.Visible = "off";
                    app.CellEfficiencySpinnerLabelYplus.Visible = "off";
                    app.EffecAreaRatioSpinnerYplus.Visible = "off";
                    app.EffecAreaRatioSpinnerLabelYplus.Visible = "off";
                case "Black"
                    app.param.facesMaterial(3) = "blackCoating";
                    app.CellEfficiencySpinnerYplus.Visible = "off";
                    app.CellEfficiencySpinnerLabelYplus.Visible = "off";
                    app.EffecAreaRatioSpinnerYplus.Visible = "off";
                    app.EffecAreaRatioSpinnerLabelYplus.Visible = "off";
                case "White"
                    app.param.facesMaterial(3) = "whiteCoating";
                    app.CellEfficiencySpinnerYplus.Visible = "off";
                    app.CellEfficiencySpinnerLabelYplus.Visible = "off";
                    app.EffecAreaRatioSpinnerYplus.Visible = "off";
                    app.EffecAreaRatioSpinnerLabelYplus.Visible = "off";
                case "Solar Panel"
                    app.param.facesMaterial(3) = "solarPanel";
                    app.CellEfficiencySpinnerYplus.Visible = "on";
                    app.CellEfficiencySpinnerLabelYplus.Visible = "on";
                    app.EffecAreaRatioSpinnerYplus.Visible = "on";
                    app.EffecAreaRatioSpinnerLabelYplus.Visible = "on";
            end
            plotCubesatView(app.param, app.UIAxesCubeSat);
            
        end

        % Callback function
        function CoatingButtonYminusSelectionChanged(app, event)
            selectedButton = app.CoatingButtonYminus.SelectedObject;
            switch selectedButton.Text
                case "Gold"
                    app.param.facesMaterial(4) = "goldCoating";
                    app.CellEfficiencySpinnerYminus.Visible = "off";
                    app.CellEfficiencySpinnerLabelYminus.Visible = "off";
                    app.EffecAreaRatioSpinnerYminus.Visible = "off";
                    app.EffecAreaRatioSpinnerLabelYminus.Visible = "off";
                case "Black"
                    app.param.facesMaterial(4) = "blackCoating";
                    app.CellEfficiencySpinnerYminus.Visible = "off";
                    app.CellEfficiencySpinnerLabelYminus.Visible = "off";
                    app.EffecAreaRatioSpinnerYminus.Visible = "off";
                    app.EffecAreaRatioSpinnerLabelYminus.Visible = "off";
                case "White"
                    app.param.facesMaterial(4) = "whiteCoating";
                    app.CellEfficiencySpinnerYminus.Visible = "off";
                    app.CellEfficiencySpinnerLabelYminus.Visible = "off";
                    app.EffecAreaRatioSpinnerYminus.Visible = "off";
                    app.EffecAreaRatioSpinnerLabelYminus.Visible = "off";
                case "Solar Panel"
                    app.param.facesMaterial(4) = "solarPanel";
                    app.CellEfficiencySpinnerYminus.Visible = "on";
                    app.CellEfficiencySpinnerLabelYminus.Visible = "on";
                    app.EffecAreaRatioSpinnerYminus.Visible = "on";
                    app.EffecAreaRatioSpinnerLabelYminus.Visible = "on";
            end
            plotCubesatView(app.param, app.UIAxesCubeSat);
            
        end

        % Callback function
        function CoatingButtonZplusSelectionChanged(app, event)
            selectedButton = app.CoatingButtonZplus.SelectedObject;
            switch selectedButton.Text
                case "Gold"
                    app.param.facesMaterial(5) = "goldCoating";
                    app.CellEfficiencySpinnerZplus.Visible = "off";
                    app.CellEfficiencySpinnerLabelZplus.Visible = "off";
                    app.EffecAreaRatioSpinnerZplus.Visible = "off";
                    app.EffecAreaRatioSpinnerLabelZplus.Visible = "off";
                case "Black"
                    app.param.facesMaterial(5) = "blackCoating";
                    app.CellEfficiencySpinnerZplus.Visible = "off";
                    app.CellEfficiencySpinnerLabelZplus.Visible = "off";
                    app.EffecAreaRatioSpinnerZplus.Visible = "off";
                    app.EffecAreaRatioSpinnerLabelZplus.Visible = "off";
                case "White"
                    app.param.facesMaterial(5) = "whiteCoating";
                    app.CellEfficiencySpinnerZplus.Visible = "off";
                    app.CellEfficiencySpinnerLabelZplus.Visible = "off";
                    app.EffecAreaRatioSpinnerZplus.Visible = "off";
                    app.EffecAreaRatioSpinnerLabelZplus.Visible = "off";
                case "Solar Panel"
                    app.param.facesMaterial(5) = "solarPanel";
                    app.CellEfficiencySpinnerZplus.Visible = "on";
                    app.CellEfficiencySpinnerLabelZplus.Visible = "on";
                    app.EffecAreaRatioSpinnerZplus.Visible = "on";
                    app.EffecAreaRatioSpinnerLabelZplus.Visible = "on";
            end
            plotCubesatView(app.param, app.UIAxesCubeSat);
            
        end

        % Callback function
        function CoatingButtonZminusSelectionChanged(app, event)
            selectedButton = app.CoatingButtonZminus.SelectedObject;
            switch selectedButton.Text
                case "Gold"
                    app.param.facesMaterial(6) = "goldCoating";
                    app.CellEfficiencySpinnerZminus.Visible = "off";
                    app.CellEfficiencySpinnerLabelZminus.Visible = "off";
                    app.EffecAreaRatioSpinnerZminus.Visible = "off";
                    app.EffecAreaRatioSpinnerLabelZminus.Visible = "off";
                case "Black"
                    app.param.facesMaterial(6) = "blackCoating";
                    app.CellEfficiencySpinnerZminus.Visible = "off";
                    app.CellEfficiencySpinnerLabelZminus.Visible = "off";
                    app.EffecAreaRatioSpinnerZminus.Visible = "off";
                    app.EffecAreaRatioSpinnerLabelZminus.Visible = "off";
                case "White"
                    app.param.facesMaterial(6) = "whiteCoating";
                    app.CellEfficiencySpinnerZminus.Visible = "off";
                    app.CellEfficiencySpinnerLabelZminus.Visible = "off";
                    app.EffecAreaRatioSpinnerZminus.Visible = "off";
                    app.EffecAreaRatioSpinnerLabelZminus.Visible = "off";
                case "Solar Panel"
                    app.param.facesMaterial(6) = "solarPanel";
                    app.CellEfficiencySpinnerZminus.Visible = "on";
                    app.CellEfficiencySpinnerLabelZminus.Visible = "on";
                    app.EffecAreaRatioSpinnerZminus.Visible = "on";
                    app.EffecAreaRatioSpinnerLabelZminus.Visible = "on";
            end
            plotCubesatView(app.param, app.UIAxesCubeSat);
            
        end

        % Value changed function: CellEfficiencySpinnerXplus
        function CellEfficiencySpinnerXplusValueChanged(app, event)
            value = app.CellEfficiencySpinnerXplus.Value;
            app.param.solarCellEff(1) = value/100;
        end

        % Value changed function: CellEfficiencySpinnerXminus
        function CellEfficiencySpinnerXminusValueChanged(app, event)
            value = app.CellEfficiencySpinnerXminus.Value;
            app.param.solarCellEff(2) = value/100;
        end

        % Value changed function: CellEfficiencySpinnerYplus
        function CellEfficiencySpinnerYplusValueChanged(app, event)
            value = app.CellEfficiencySpinnerYplus.Value;
            app.param.solarCellEff(3) = value/100;
        end

        % Value changed function: CellEfficiencySpinnerYminus
        function CellEfficiencySpinnerYminusValueChanged(app, event)
            value = app.CellEfficiencySpinnerYminus.Value;
            app.param.solarCellEff(4) = value/100;
        end

        % Value changed function: CellEfficiencySpinnerZplus
        function CellEfficiencySpinnerZplusValueChanged(app, event)
            value = app.CellEfficiencySpinnerZplus.Value;
            app.param.solarCellEff(5) = value/100;
        end

        % Value changed function: CellEfficiencySpinnerZminus
        function CellEfficiencySpinnerZminusValueChanged(app, event)
            value = app.CellEfficiencySpinnerZminus.Value;
            app.param.solarCellEff(6) = value/100;
        end

        % Value changed function: EffecAreaRatioSpinnerXplus
        function EffecAreaRatioSpinnerXplusValueChanged(app, event)
            value = app.EffecAreaRatioSpinnerXplus.Value;
            app.param.effectiveAreaRatio(1) = value/100;
        end

        % Value changed function: EffecAreaRatioSpinnerXminus
        function EffecAreaRatioSpinnerXminusValueChanged(app, event)
            value = app.EffecAreaRatioSpinnerXminus.Value;
            app.param.effectiveAreaRatio(2) = value/100;
        end

        % Value changed function: EffecAreaRatioSpinnerYplus
        function EffecAreaRatioSpinnerYplusValueChanged(app, event)
            value = app.EffecAreaRatioSpinnerYplus.Value;
            app.param.effectiveAreaRatio(3) = value/100;
        end

        % Value changed function: EffecAreaRatioSpinnerYminus
        function EffecAreaRatioSpinnerYminusValueChanged(app, event)
            value = app.EffecAreaRatioSpinnerYminus.Value;
            app.param.effectiveAreaRatio(4) = value/100;
        end

        % Value changed function: EffecAreaRatioSpinnerZplus
        function EffecAreaRatioSpinnerZplusValueChanged(app, event)
            value = app.EffecAreaRatioSpinnerZplus.Value;
            app.param.effectiveAreaRatio(5) = value/100;
        end

        % Value changed function: EffecAreaRatioSpinnerZminus
        function EffecAreaRatioSpinnerZminusValueChanged(app, event)
            value = app.EffecAreaRatioSpinnerZminus.Value;
            app.param.effectiveAreaRatio(6) = value/100;
        end

        % Value changed function: BodyMasskgSpinner
        function BodyMasskgSpinnerValueChanged(app, event)
            value = app.BodyMasskgSpinner.Value;
            app.param.satelliteMass = value;
            % Update Mass Table
            massVector = app.param.satelliteMass * [app.param.internalNode2TotalMassRatio; (1-app.param.internalNode2TotalMassRatio)* app.param.facesMassDistribution'];
            app.UITableMassDistribution.Data = table(["Internal Node";"X+";"X-";"Y+";"Y-";"Z+";"Z-"],massVector,'VariableNames',{'Node','Mass'});
        end

        % Value changed function: SpinnerRXplus
        function SpinnerRXplusValueChanged(app, event)
            value = app.SpinnerRXplus.Value;
            app.param.resistance(1) = value;
        end

        % Value changed function: SpinnerRXminus
        function SpinnerRXminusValueChanged(app, event)
            value = app.SpinnerRXminus.Value;
            app.param.resistance(2) = value;
        end

        % Value changed function: SpinnerRYplus
        function SpinnerRYplusValueChanged(app, event)
            value = app.SpinnerRYplus.Value;
            app.param.resistance(3) = value;
        end

        % Value changed function: SpinnerRYminus
        function SpinnerRYminusValueChanged(app, event)
            value = app.SpinnerRYminus.Value;
            app.param.resistance(4) = value;
        end

        % Value changed function: SpinnerRZplus
        function SpinnerRZplusValueChanged(app, event)
            value = app.SpinnerRZplus.Value;
            app.param.resistance(5) = value;
        end

        % Value changed function: SpinnerRZminus
        function SpinnerRZminusValueChanged(app, event)
            value = app.SpinnerRZminus.Value;
            app.param.resistance(6) = value;
        end

        % Button pushed function: ComputeThermalButton
        function ComputeThermalButtonPushed(app, event)
            if(app.param.orb.prop.flag && app.param.pwr.flag)
                app.ComputeThermalStatusLabel.Visible = "on";
                app.ComputeThermalStatusLabel.FontColor = "k";
                app.ComputeThermalStatusLabel.Text = "Processing";
                drawnow; % Refresh the status label
                try
                    % Compute radiative coefficient
                    sigma = 5.67e-8; % Stefan-Boltzmann constant (W · m -2 · K -4 )
                    app.param.krad = app.param.emissivity*sigma;
                
                    % Mass paramters
                    app.param.facesMass = app.param.facesMassDistribution * (1-app.param.internalNode2TotalMassRatio) * app.param.satelliteMass;
                    app.param.internalNodeMass = app.param.internalNode2TotalMassRatio * app.param.satelliteMass;

                    % MLI blanket parameters
                    [app.param.facesBlanketMass, app.param.resistanceBlanket, app.param.resistanceInterFacesBlanket] = setBlanketParameters(app.param);
                    
                    % Run thermal analysis
                    [thermal,flag] = computeThermal(app.param);
                    app.param.thermal = thermal;
                    app.param.thermal.flag = flag;

                    % Plot results
                    plotSolarRadiationInput(app.UIAxesSolarRadiation, thermal, app.param.plot.solarRadiationInput.showFacesFlag);
                    plotIRRadiationInput(app.UIAxesIRinput, thermal, app.param.plot.IRRadiationInput.showFacesFlag, app.param.plot.IRRadiationInput.showAbsorbedIRFlag, app.param.plot.IRRadiationInput.showRadiatedIRFlag);
                    plotAlbedoRadiationInput(app.UIAxesAlbedoInput, thermal, app.param.plot.albedoRadiationInput.showFacesFlag);
                    plotInternalTemperatures(app.UIAxesInternalNodesTemp, thermal)
                    plotFacesTemperatures(app.UIAxesFacesTemp, thermal)

                    
                    app.ComputeThermalStatusLabel.Text = strcat("Completed in ", num2str(thermal.timeElapsedProcessing,'%4.1f'),"s");
                    app.ComputeThermalStatusLabel.FontColor = '#0B0';
                catch ME
                    app.param.thermal.flag = 0;
                    app.ComputeThermalStatusLabel.Text = "Failed!";
                    app.ComputeThermalStatusLabel.FontColor = "r";
                end

            else
                if(~app.param.orb.prop.flag &&  ~app.param.pwr.flag)
                    errordlg({'You first need to:','(1) propagate orbit','(2) compute power'});
                elseif(app.param.orb.prop.flag &&  ~app.param.pwr.flag)
                    errordlg('You need to compute power');
                end
            end

        end

        % Value changed function: IntermalnodetototalmassratioSpinner
        function IntermalnodetototalmassratioSpinnerValueChanged(app, event)
            value = app.IntermalnodetototalmassratioSpinner.Value;
            app.param.internalNode2TotalMassRatio = value/100;

            % Update Mass Table
            massVector = app.param.satelliteMass * [app.param.internalNode2TotalMassRatio; (1-app.param.internalNode2TotalMassRatio)* app.param.facesMassDistribution'];
            app.UITableMassDistribution.Data = table(["Internal Node";"X+";"X-";"Y+";"Y-";"Z+";"Z-"],massVector,'VariableNames',{'Node','Mass'});
        end

        % Value changed function: IntermalnodeSpecificHeatJkgKSpinner
        function IntermalnodeSpecificHeatJkgKSpinnerValueChanged(app, event)
            value = app.IntermalnodeSpecificHeatJkgKSpinner.Value;
            app.param.internalNodeCp = value; %J/kg/K
        end

        % Value changed function: SurfaceTypeDropDownXplus
        function SurfaceTypeDropDownXplusValueChanged(app, event)
            value = app.SurfaceTypeDropDownXplus.Value;
            faceID = 1; % X+

            app.param.facesMaterial(faceID) = value;
            plotCubesatView(app.param, app.UIAxesCubeSat);

            if(value == "Custom")
                app.EmissivitySpinnerXplus.Enable = "on";
                app.AbsorptivitySpinnerXplus.Enable = "on";
                app.CpSpinnerXplus.Enable = "on";
            else
                p = getPropertiesFromMaterial(value);
                app.param.emissivity(faceID) = p.emiss;
                app.param.absorptivity(faceID)= p.absorp;

                app.EmissivitySpinnerXplus.Value = p.emiss;
                app.EmissivitySpinnerXplus.Enable = "off";
                app.AbsorptivitySpinnerXplus.Value = p.absorp;
                app.AbsorptivitySpinnerXplus.Enable = "off";
                app.RatioEditFieldXplus.Value = p.absorp/p.emiss;
                app.CpSpinnerXplus.Value = p.Cp;
                app.CpSpinnerXplus.Enable = "off";
            end

            if(value ~= "Solar Panel")
                app.CellEfficiencySpinnerXplus.Visible = "off";
                app.CellEfficiencySpinnerLabelXplus.Visible = "off";
                app.EffecAreaRatioSpinnerXplus.Visible = "off";
                app.EffecAreaRatioSpinnerLabelXplus.Visible = "off";

            else
                app.CellEfficiencySpinnerXplus.Visible = "on";
                app.CellEfficiencySpinnerLabelXplus.Visible = "on";
                app.EffecAreaRatioSpinnerXplus.Visible = "on";
                app.EffecAreaRatioSpinnerLabelXplus.Visible = "on";
            end
            
        end

        % Value changed function: AbsorptivitySpinnerXplus
        function AbsorptivitySpinnerXplusValueChanged(app, event)
            value = app.AbsorptivitySpinnerXplus.Value;
            faceID = 1; % X+
            app.param.absorptivity(faceID) = value;
            app.RatioEditFieldXplus.Value = value/app.EmissivitySpinnerXplus.Value;
        end

        % Value changed function: EmissivitySpinnerXplus
        function EmissivitySpinnerXplusValueChanged(app, event)
            value = app.EmissivitySpinnerXplus.Value;
            faceID = 1; % X+
            app.param.emissivity(faceID) = value;
            app.RatioEditFieldXplus.Value = app.AbsorptivitySpinnerXplus.Value/value;
        end

        % Value changed function: CpSpinnerXplus
        function CpSpinnerXplusValueChanged(app, event)
            value = app.CpSpinnerXplus.Value;
            faceID = 1; % X+
            app.param.facesCp(faceID) = value;
        end

        % Value changed function: SurfaceTypeDropDownXminus
        function SurfaceTypeDropDownXminusValueChanged(app, event)
            value = app.SurfaceTypeDropDownXminus.Value;
            faceID = 2; % X-

            app.param.facesMaterial(faceID) = value;
            plotCubesatView(app.param, app.UIAxesCubeSat);

            if(value == "Custom")
                app.EmissivitySpinnerXminus.Enable = "on";
                app.AbsorptivitySpinnerXminus.Enable = "on";
                app.CpSpinnerXminus.Enable = "on";
            else
                p = getPropertiesFromMaterial(value);
                app.param.emissivity(faceID) = p.emiss;
                app.param.absorptivity(faceID)= p.absorp;
                app.EmissivitySpinnerXminus.Value = p.emiss;
                app.EmissivitySpinnerXminus.Enable = "off";
                app.AbsorptivitySpinnerXminus.Value = p.absorp;
                app.AbsorptivitySpinnerXminus.Enable = "off";
                app.RatioEditFieldXminus.Value = p.absorp/p.emiss;
                app.CpSpinnerXminus.Value = p.Cp;
                app.CpSpinnerXminus.Enable = "off";
            end

            if(value ~= "Solar Panel")
                app.CellEfficiencySpinnerXminus.Visible = "off";
                app.CellEfficiencySpinnerLabelXminus.Visible = "off";
                app.EffecAreaRatioSpinnerXminus.Visible = "off";
                app.EffecAreaRatioSpinnerLabelXminus.Visible = "off";

            else
                app.CellEfficiencySpinnerXminus.Visible = "on";
                app.CellEfficiencySpinnerLabelXminus.Visible = "on";
                app.EffecAreaRatioSpinnerXminus.Visible = "on";
                app.EffecAreaRatioSpinnerLabelXminus.Visible = "on";
            end
            
        end

        % Value changed function: AbsorptivitySpinnerXminus
        function AbsorptivitySpinnerXminusValueChanged(app, event)
            value = app.AbsorptivitySpinnerXminus.Value;
            faceID = 2; % X-
            app.param.absorptivity(faceID) = value;
            app.RatioEditFieldXminus.Value = value/app.EmissivitySpinnerXminus.Value;
        end

        % Value changed function: EmissivitySpinnerXminus
        function EmissivitySpinnerXminusValueChanged(app, event)
            value = app.EmissivitySpinnerXminus.Value;
            faceID = 2; % X-
            app.param.emissivity(faceID) = value;
            app.RatioEditFieldXminus.Value = app.AbsorptivitySpinnerXminus.Value/value;
        end

        % Value changed function: CpSpinnerXminus
        function CpSpinnerXminusValueChanged(app, event)
            value = app.CpSpinnerXminus.Value;
            faceID = 2; % X-
            app.param.facesCp(faceID) = value;
        end

        % Value changed function: SurfaceTypeDropDownYplus
        function SurfaceTypeDropDownYplusValueChanged(app, event)
            value = app.SurfaceTypeDropDownYplus.Value;
            faceID = 3; % Y+

            app.param.facesMaterial(faceID) = value;
            plotCubesatView(app.param, app.UIAxesCubeSat);

            if(value == "Custom")
                app.EmissivitySpinnerYplus.Enable = "on";
                app.AbsorptivitySpinnerYplus.Enable = "on";
                app.CpSpinnerYplus.Enable = "on";
            else
                p = getPropertiesFromMaterial(value);
                app.param.emissivity(faceID) = p.emiss;
                app.param.absorptivity(faceID)= p.absorp;
                app.EmissivitySpinnerYplus.Value = p.emiss;
                app.EmissivitySpinnerYplus.Enable = "off";
                app.AbsorptivitySpinnerYplus.Value = p.absorp;
                app.AbsorptivitySpinnerYplus.Enable = "off";
                app.RatioEditFieldYplus.Value = p.absorp/p.emiss;
                app.CpSpinnerYplus.Value = p.Cp;
                app.CpSpinnerYplus.Enable = "off";
            end

            if(value ~= "Solar Panel")
                app.CellEfficiencySpinnerYplus.Visible = "off";
                app.CellEfficiencySpinnerLabelYplus.Visible = "off";
                app.EffecAreaRatioSpinnerYplus.Visible = "off";
                app.EffecAreaRatioSpinnerLabelYplus.Visible = "off";

            else
                app.CellEfficiencySpinnerYplus.Visible = "on";
                app.CellEfficiencySpinnerLabelYplus.Visible = "on";
                app.EffecAreaRatioSpinnerYplus.Visible = "on";
                app.EffecAreaRatioSpinnerLabelYplus.Visible = "on";
            end
            
        end

        % Value changed function: AbsorptivitySpinnerYplus
        function AbsorptivitySpinnerYplusValueChanged(app, event)
            value = app.AbsorptivitySpinnerYplus.Value;
            faceID = 3; % Y+
            app.param.absorptivity(faceID) = value;
            app.RatioEditFieldYplus.Value = value/app.EmissivitySpinnerYplus.Value;
        end

        % Value changed function: EmissivitySpinnerYplus
        function EmissivitySpinnerYplusValueChanged(app, event)
            value = app.EmissivitySpinnerYplus.Value;
            faceID = 3; % Y+
            app.param.emissivity(faceID) = value;
            app.RatioEditFieldYplus.Value = app.AbsorptivitySpinnerYplus.Value/value;
        end

        % Value changed function: CpSpinnerYplus
        function CpSpinnerYplusValueChanged(app, event)
            value = app.CpSpinnerYplus.Value;
            faceID = 3; % Y+
            app.param.facesCp(faceID) = value;
        end

        % Value changed function: SurfaceTypeDropDownYminus
        function SurfaceTypeDropDownYminusValueChanged(app, event)
            value = app.SurfaceTypeDropDownYminus.Value;
            faceID = 4; % Y-

            app.param.facesMaterial(faceID) = value;
            plotCubesatView(app.param, app.UIAxesCubeSat);

            if(value == "Custom")
                app.EmissivitySpinnerYminus.Enable = "on";
                app.AbsorptivitySpinnerYminus.Enable = "on";
                app.CpSpinnerYminus.Enable = "on";
            else
                p = getPropertiesFromMaterial(value);
                app.param.emissivity(faceID) = p.emiss;
                app.param.absorptivity(faceID)= p.absorp;
                app.EmissivitySpinnerYminus.Value = p.emiss;
                app.EmissivitySpinnerYminus.Enable = "off";
                app.AbsorptivitySpinnerYminus.Value = p.absorp;
                app.AbsorptivitySpinnerYminus.Enable = "off";
                app.RatioEditFieldYminus.Value = p.absorp/p.emiss;
                app.CpSpinnerYminus.Value = p.Cp;
                app.CpSpinnerYminus.Enable = "off";
            end

            if(value ~= "Solar Panel")
                app.CellEfficiencySpinnerYminus.Visible = "off";
                app.CellEfficiencySpinnerLabelYminus.Visible = "off";
                app.EffecAreaRatioSpinnerYminus.Visible = "off";
                app.EffecAreaRatioSpinnerLabelYminus.Visible = "off";

            else
                app.CellEfficiencySpinnerYminus.Visible = "on";
                app.CellEfficiencySpinnerLabelYminus.Visible = "on";
                app.EffecAreaRatioSpinnerYminus.Visible = "on";
                app.EffecAreaRatioSpinnerLabelYminus.Visible = "on";
            end
        end

        % Value changed function: AbsorptivitySpinnerYminus
        function AbsorptivitySpinnerYminusValueChanged(app, event)
            value = app.AbsorptivitySpinnerYminus.Value;
            faceID = 4; % Y-
            app.param.absorptivity(faceID) = value;
            app.RatioEditFieldYminus.Value = value/app.EmissivitySpinnerYminus.Value;
        end

        % Value changed function: EmissivitySpinnerYminus
        function EmissivitySpinnerYminusValueChanged(app, event)
            value = app.EmissivitySpinnerYminus.Value;
            faceID = 4; % Y-
            app.param.emissivity(faceID) = value;
            app.RatioEditFieldYminus.Value = app.AbsorptivitySpinnerYminus.Value/value;
        end

        % Value changed function: CpSpinnerYminus
        function CpSpinnerYminusValueChanged(app, event)
            value = app.CpSpinnerYminus.Value;
            faceID = 4; % Y-
            app.param.facesCp(faceID) = value;
        end

        % Value changed function: SurfaceTypeDropDownZplus
        function SurfaceTypeDropDownZplusValueChanged(app, event)
            value = app.SurfaceTypeDropDownZplus.Value;
            faceID = 5; % Z+

            app.param.facesMaterial(faceID) = value;
            plotCubesatView(app.param, app.UIAxesCubeSat);

            if(value == "Custom")
                app.EmissivitySpinnerZplus.Enable = "on";
                app.AbsorptivitySpinnerZplus.Enable = "on";
                app.CpSpinnerZplus.Enable = "on";
            else
                p = getPropertiesFromMaterial(value);
                app.param.emissivity(faceID) = p.emiss;
                app.param.absorptivity(faceID)= p.absorp;
                app.EmissivitySpinnerZplus.Value = p.emiss;
                app.EmissivitySpinnerZplus.Enable = "off";
                app.AbsorptivitySpinnerZplus.Value = p.absorp;
                app.AbsorptivitySpinnerZplus.Enable = "off";
                app.RatioEditFieldZplus.Value = p.absorp/p.emiss;
                app.CpSpinnerZplus.Value = p.Cp;
                app.CpSpinnerZplus.Enable = "off";
            end

            if(value ~= "Solar Panel")
                app.CellEfficiencySpinnerZplus.Visible = "off";
                app.CellEfficiencySpinnerLabelZplus.Visible = "off";
                app.EffecAreaRatioSpinnerZplus.Visible = "off";
                app.EffecAreaRatioSpinnerLabelZplus.Visible = "off";

            else
                app.CellEfficiencySpinnerZplus.Visible = "on";
                app.CellEfficiencySpinnerLabelZplus.Visible = "on";
                app.EffecAreaRatioSpinnerZplus.Visible = "on";
                app.EffecAreaRatioSpinnerLabelZplus.Visible = "on";
            end
        end

        % Value changed function: AbsorptivitySpinnerZplus
        function AbsorptivitySpinnerZplusValueChanged(app, event)
            value = app.AbsorptivitySpinnerZplus.Value;
            faceID = 5; % Z+
            app.param.absorptivity(faceID) = value;
            app.RatioEditFieldZplus.Value = value/app.EmissivitySpinnerZplus.Value;
        end

        % Value changed function: EmissivitySpinnerZplus
        function EmissivitySpinnerZplusValueChanged(app, event)
            value = app.EmissivitySpinnerZplus.Value;
            faceID = 5; % Z+
            app.param.emissivity(faceID) = value;
            app.RatioEditFieldZplus.Value = app.AbsorptivitySpinnerZplus.Value/value;
        end

        % Value changed function: CpSpinnerZplus
        function CpSpinnerZplusValueChanged(app, event)
            value = app.CpSpinnerZplus.Value;
            faceID = 5; % Z+
            app.param.facesCp(faceID) = value;
        end

        % Value changed function: SurfaceTypeDropDownZminus
        function SurfaceTypeDropDownZminusValueChanged(app, event)
            value = app.SurfaceTypeDropDownZminus.Value;
            faceID = 6; % Z-

            app.param.facesMaterial(faceID) = value;
            plotCubesatView(app.param, app.UIAxesCubeSat);

            if(value == "Custom")
                app.EmissivitySpinnerZminus.Enable = "on";
                app.AbsorptivitySpinnerZminus.Enable = "on";
                app.CpSpinnerZminus.Enable = "on";
            else
                p = getPropertiesFromMaterial(value);
                app.param.emissivity(faceID) = p.emiss;
                app.param.absorptivity(faceID)= p.absorp;
                app.EmissivitySpinnerZminus.Value = p.emiss;
                app.EmissivitySpinnerZminus.Enable = "off";
                app.AbsorptivitySpinnerZminus.Value = p.absorp;
                app.AbsorptivitySpinnerZminus.Enable = "off";
                app.RatioEditFieldZminus.Value = p.absorp/p.emiss;
                app.CpSpinnerZminus.Value = p.Cp;
                app.CpSpinnerZminus.Enable = "off";
            end

            if(value ~= "Solar Panel")
                app.CellEfficiencySpinnerZminus.Visible = "off";
                app.CellEfficiencySpinnerLabelZminus.Visible = "off";
                app.EffecAreaRatioSpinnerZminus.Visible = "off";
                app.EffecAreaRatioSpinnerLabelZminus.Visible = "off";

            else
                app.CellEfficiencySpinnerZminus.Visible = "on";
                app.CellEfficiencySpinnerLabelZminus.Visible = "on";
                app.EffecAreaRatioSpinnerZminus.Visible = "on";
                app.EffecAreaRatioSpinnerLabelZminus.Visible = "on";
            end
        end

        % Value changed function: AbsorptivitySpinnerZminus
        function AbsorptivitySpinnerZminusValueChanged(app, event)
            value = app.AbsorptivitySpinnerZminus.Value;
            faceID = 6; % Z-
            app.param.absorptivity(faceID) = value;
            app.RatioEditFieldZminus.Value = value/app.EmissivitySpinnerZminus.Value;
        end

        % Value changed function: EmissivitySpinnerZminus
        function EmissivitySpinnerZminusValueChanged(app, event)
            value = app.EmissivitySpinnerZminus.Value;
            faceID = 6; % Z-
            app.param.emissivity(faceID) = value;
            app.RatioEditFieldZminus.Value = app.AbsorptivitySpinnerZminus.Value/value;
        end

        % Value changed function: CpSpinnerZminus
        function CpSpinnerZminusValueChanged(app, event)
            value = app.CpSpinnerZminus.Value;
            faceID = 6; % Z-
            app.param.facesCp(faceID) = value;
        end

        % Value changed function: CentralBodyDropDown
        function CentralBodyDropDownValueChanged(app, event)
            value = app.CentralBodyDropDown.Value;
            app.param.orb.centralBody = value;
            app.UpdateOrbitStatusLabel.Text = '';
            app.param.orb.prop.flag = 0;
            switch value
                case "Moon"
                    app.param.Albedo = 0.07;
                    app.IRmodelDropDown.Items = app.param.moonIRmodelList;
                    app.param.IRmodelItemList = app.param.moonIRmodelList;
                    app.PropagatorDropDown.Items = app.param.moonOrbPropagatorList;
                    app.param.propagatorItemList = app.param.moonOrbPropagatorList;
                    app.param.orb.orbitPropagator = app.param.moonOrbPropagatorList(1);
                    app.param.Tir_Day = 350;
                    app.param.Tir_Night = 90;
                    app.param.IRmodel = app.param.IRmodelItemList(1);
                    app.DayTempKEditField.Visible = "on";
                    app.DayTempKEditFieldLabel.Visible = "on";
                    app.param.orb.prop.flag = 0;
                case "Earth"
                    app.param.Albedo = 0.3; 
                    app.IRmodelDropDown.Items = app.param.earthIRmodelList;
                    app.param.IRmodelItemList = app.param.earthIRmodelList;
                    app.PropagatorDropDown.Items = app.param.earthOrbPropagatorList;
                    app.param.propagatorItemList = app.param.earthOrbPropagatorList;
                    app.param.IRmodel = app.param.earthIRmodelList(1);
                    app.param.Tir_Day = 254;
                    app.param.Tir_Night = 254;
                    app.param.orb.orbitPropagator = app.param.earthOrbPropagatorList(1);
                    app.DayTempKEditField.Visible = "on";
                    app.DayTempKEditFieldLabel.Visible = "on";
                    app.param.orb.prop.flag = 0;
            end
            plotOrbit3D(app.UIAxesOrbit3D, value);
            app.AlbedoEditField.Value = app.param.Albedo ;
            app.DayTempKEditField.Value =  app.param.Tir_Day;
            app.NightTempKEditField.Value = app.param.Tir_Night;
        end

        % Value changed function: SemimajoraxiskmEditField
        function SemimajoraxiskmEditFieldValueChanged(app, event)
            value = app.SemimajoraxiskmEditField.Value;
            app.param.orb.semiMajorAxis = value;
            app.UpdateOrbitStatusLabel.Text = '';
            app.param.orb.prop.flag = 0;
        end

        % Value changed function: EccentricityEditField
        function EccentricityEditFieldValueChanged(app, event)
            value = app.EccentricityEditField.Value;
            app.param.orb.eccentricity = value;
            app.UpdateOrbitStatusLabel.Text = '';
            app.param.orb.prop.flag = 0;
        end

        % Value changed function: InclinationEditField
        function InclinationEditFieldValueChanged(app, event)
            value = app.InclinationEditField.Value;
            app.param.orb.inclination = value;
            app.UpdateOrbitStatusLabel.Text = '';
            app.param.orb.prop.flag = 0;
        end

        % Value changed function: RAANEditField
        function RAANEditFieldValueChanged(app, event)
            value = app.RAANEditField.Value;
            app.param.orb.RAAN = value;
            app.UpdateOrbitStatusLabel.Text = '';
            app.param.orb.prop.flag = 0;
        end

        % Value changed function: ArgumentofPeriapsisEditField
        function ArgumentofPeriapsisEditFieldValueChanged(app, event)
            value = app.ArgumentofPeriapsisEditField.Value;
            app.param.orb.argumentOfPeriapsis = value;
            app.UpdateOrbitStatusLabel.Text = '';
            app.param.orb.prop.flag = 0;
        end

        % Value changed function: TrueAnomalyEditField
        function TrueAnomalyEditFieldValueChanged(app, event)
            value = app.TrueAnomalyEditField.Value;
            app.param.orb.trueAnomaly = value;
            app.UpdateOrbitStatusLabel.Text = '';
            app.param.orb.prop.flag = 0;
        end

        % Value changed function: EpochDatePicker
        function EpochDatePickerValueChanged(app, event)
            value = app.EpochDatePicker.Value;
            app.param.orb.startDate = value;
            app.UpdateOrbitStatusLabel.Text = '';
            app.param.orb.prop.flag = 0;
        end

        % Value changed function: HSpinner
        function HSpinnerValueChanged(app, event)
            value = app.HSpinner.Value;
            app.param.orb.startHour = value;
            app.UpdateOrbitStatusLabel.Text = '';
            app.param.orb.prop.flag = 0;
        end

        % Value changed function: MSpinner
        function MSpinnerValueChanged(app, event)
            value = app.MSpinner.Value;
            app.param.orb.startMinute = value;
            app.UpdateOrbitStatusLabel.Text = '';
            app.param.orb.prop.flag = 0;
        end

        % Value changed function: DurationhoursSpinner
        function DurationhoursSpinnerValueChanged(app, event)
            value = app.DurationhoursSpinner.Value;
            app.param.orb.durationHours = value;
            app.UpdateOrbitStatusLabel.Text = '';
            app.param.orb.prop.flag = 0;
        end

        % Button pushed function: UpdateOrbitButton
        function UpdateOrbitButtonPushed(app, event)
            app.UpdateOrbitStatusLabel.Visible = "on";
            app.UpdateOrbitStatusLabel.FontColor = "k";
            app.UpdateOrbitStatusLabel.Text = "Processing";
            drawnow; % Refresh the status label
            assignin('base', 'param', app.param)
            tic;
            switch app.param.orb.centralBody
                case "Earth"
                    [propData, flag] = propagateEarthOrbit(app.param.orb);     
                case "Moon"
                    [propData, flag] = propagateMoonOrbit(app.param.orb); 
            end
            timeElapsedProcessing =  toc;
            app.param.orb.prop = propData;
            app.param.orb.prop.flag = flag;
            if(flag)
                app.param.pwr.flag = 0;     % Power needs to be computed again
                app.ComputePowerStatusLabel.Text = '';
                app.ComputeThermalStatusLabel.Text = '';
                app.UpdateOrbitStatusLabel.Text = strcat("Completed in ", num2str(timeElapsedProcessing,'%4.1f'),"s");
                app.UpdateOrbitStatusLabel.FontColor = '#0B0'; 
    
                plotOrbit3D(app.UIAxesOrbit3D, app.param.orb.centralBody, app.param); 
                plotSunlight(app.UIAxesSunLight, propData.time_Epoch, propData.sunMagnitude)
                plotAltitude(app.UIAxesAltitude, propData.time_Epoch, propData.altitude)
                plotBetaAngle(app.UIAxesBetaAngle, propData.time_Epoch, propData.betaAngle)
            else
                app.UpdateOrbitStatusLabel.Text = "Failed";
                app.UpdateOrbitStatusLabel.FontColor = "r";
            end
        end

        % Value changed function: SolarfluxWm2EditField
        function SolarfluxWm2EditFieldValueChanged(app, event)
            value = app.SolarfluxWm2EditField.Value;
            app.param.orb.solarFlux = value;
        end

        % Value changed function: IRmodelDropDown
        function IRmodelDropDownValueChanged(app, event)
            value = app.IRmodelDropDown.Value;
            app.param.IRmodel = value;
            if(value == "Gradient")
                app.DayTempKEditField.Visible = "off";
                app.DayTempKEditFieldLabel.Visible = "off";
            else
                app.DayTempKEditField.Visible = "on";
                app.DayTempKEditFieldLabel.Visible = "on";
            end
        end

        % Value changed function: PropagatorDropDown
        function PropagatorDropDownValueChanged(app, event)
            value = app.PropagatorDropDown.Value;
            app.param.orb.orbitPropagator = value;
            app.UpdateOrbitStatusLabel.Text = '';
            app.param.orb.prop.flag = 0;
        end

        % Value changed function: NadirFaceDropDown
        function NadirFaceDropDownValueChanged(app, event)
            value = app.NadirFaceDropDown.Value;
            app.param.attitude.nadirFace = value;
            % change options for ram face based on nadir face
            faceId=char(value); 
            switch faceId(1)
                case "X"
                    app.RamFaceDropDown.Items = ["Y+", "Y-", "Z+", "Z-"];
                    app.param.attitude.ramFace = "Y+";
                case "Y"
                    app.RamFaceDropDown.Items = ["X+", "X-", "Z+", "Z-"];
                    app.param.attitude.ramFace = "X+";
                case "Z"
                    app.RamFaceDropDown.Items = ["X+", "X-", "Y+", "Y-"];
                    app.param.attitude.ramFace = "X+";
            end
            app.param.attitude.ramFaceItems = app.RamFaceDropDown.Items;
            % Refresh CubeSat
            plotCubesatView(app.param, app.UIAxesCubeSat);
        end

        % Value changed function: RamFaceDropDown
        function RamFaceDropDownValueChanged(app, event)
            value = app.RamFaceDropDown.Value;
            app.param.attitude.ramFace = value;
           % Refresh CubeSat
            plotCubesatView(app.param, app.UIAxesCubeSat);
        end

        % Value changed function: ProfileDropDown
        function ProfileDropDownValueChanged(app, event)
            value = app.ProfileDropDown.Value;
            app.param.pwrDissipationProfile = value;
            app.ComputePowerStatusLabel.Text = '';
            app.param.pwr.flag = 0;
            switch value
                case "Constant"
                    app.PwrNightWSpinner.Value = app.PwrDayWSpinner.Value;
                    app.PwrNightWSpinner.Enable = "off";
                case "Day/Night"
                    app.PwrNightWSpinner.Enable = "on";
            end
        end

        % Value changed function: PwrDayWSpinner
        function PwrDayWSpinnerValueChanged(app, event)
            value = app.PwrDayWSpinner.Value;
            app.param.pwrDissipationDaySimpleModel = value;
            app.ComputePowerStatusLabel.Text = '';
            app.param.pwr.flag = 0;
            if(app.ProfileDropDown.Value == "Constant")
                app.PwrNightWSpinner.Value = app.PwrDayWSpinner.Value;
            end
            % Update Consumption plot if orbital propagation was computed
            if(app.param.orb.prop.flag)
                plotPwrConsumption(app.UIAxesPwrConsumption, app.param.orb.prop.time_Epoch, app.param.orb.prop.sunMagnitude, app.param.pwrDissipationDaySimpleModel, app.param.pwrDissipationNightSimpleModel, app.param.pwrDissipationProfile)
            end
        end

        % Value changed function: PwrNightWSpinner
        function PwrNightWSpinnerValueChanged(app, event)
            value = app.PwrNightWSpinner.Value;
            app.param.pwrDissipationNightSimpleModel = value;
            app.ComputePowerStatusLabel.Text = '';
            app.param.pwr.flag = 0;
            % Update Consumption plot if orbital propagation was computed
            if(app.param.orb.prop.flag)
                plotPwrConsumption(app.UIAxesPwrConsumption, app.param.orb.prop.time_Epoch, app.param.orb.prop.sunMagnitude, app.param.pwrDissipationDaySimpleModel, app.param.pwrDissipationNightSimpleModel, app.param.pwrDissipationProfile)
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % Update Consumption plot if orbital propagation was computed
            if(app.param.orb.prop.flag)
                plotPwrConsumption(app.UIAxesPwrConsumption, app.param.orb.prop.time_Epoch, app.param.orb.prop.sunMagnitude, app.param.pwrDissipationDaySimpleModel, app.param.pwrDissipationNightSimpleModel, app.param.pwrDissipationProfile)
            end
        end

        % Button pushed function: ComputePowerButton
        function ComputePowerButtonPushed(app, event)
            if(app.param.orb.prop.flag)
                app.ComputePowerStatusLabel.Visible = "on";
                app.ComputePowerStatusLabel.FontColor = "k";
                app.ComputePowerStatusLabel.Text = "Processing";
                drawnow; % Refresh the status label
                try
                    tic;
                    [pwr, flag] = computePower(app.param);
                    timeElapsedProcessing = toc;
                    app.param.pwr = pwr;
                    app.param.pwr.flag = flag;
                    app.ComputePowerStatusLabel.Text = strcat("Completed in ", num2str(timeElapsedProcessing,'%4.1f'),"s");
                    app.ComputePowerStatusLabel.FontColor = '#0B0'; 
                    app.ComputeThermalStatusLabel.Text = '';
                    % Plotting
                    app.SliderTimeLine.Limits = [0; pwr.time_Epoch(end)/3600];
                    app.SliderTimeLine.Value = 0;
                    plotPwrGeneration(app.UIAxesPwrGeneration, pwr)
                    plotBatteryDepletion(app.UIAxesBatteryDepletion, pwr)
                    plotAnimatedPwrCubeSat(app.param, app.UIAxesCubeSatPowerView, 0)
                    view(app.UIAxesCubeSatPowerView, [1,1,1])
                catch
                    app.param.pwr.flag = 0;
                    app.ComputePowerStatusLabel.Text = "Failed";
                    app.ComputePowerStatusLabel.FontColor = "r";
                end

            else
                errordlg('You need to propagate the orbit first');
            end
        end

        % Selection changed function: PanelTypeButtonGroupXplus
        function PanelTypeButtonGroupXplusSelectionChanged(app, event)
            selectedButton = app.PanelTypeButtonGroupXplus.SelectedObject;
            faceId = 1; % X+
            app.param.deployable.type(faceId) = selectedButton.Text;
            if( (selectedButton.Text == "Fixed") || (selectedButton.Text == "Tracking"))
                app.HingeDropDownXplus.Enable = "on";
                app.SizeDropDownXplus.Enable = "on";
                app.CellEfficiencySpinnerXplusDeployable.Enable = "on";
                app.EffecAreaRatioSpinnerXplusDeployable.Enable = "on";
                if(selectedButton.Text == "Fixed")
                    app.FlipPanelCheckBoxXplus.Enable = "on";
                else
                    app.FlipPanelCheckBoxXplus.Enable = "off";
                end

                plotCubesatView(app.param, app.UIAxesCubeSat);
            else
                app.HingeDropDownXplus.Enable = "off";
                app.SizeDropDownXplus.Enable = "off";
                app.CellEfficiencySpinnerXplusDeployable.Enable = "off";
                app.EffecAreaRatioSpinnerXplusDeployable.Enable = "off";
                app.FlipPanelCheckBoxXplus.Enable = "off";
                plotCubesatView(app.param, app.UIAxesCubeSat);
            end
        end

        % Value changed function: HingeDropDownXplus
        function HingeDropDownXplusValueChanged(app, event)
            value = app.HingeDropDownXplus.Value;
            faceId = 1; % X+
            app.param.deployable.hinge(faceId) = value;

            plotCubesatView(app.param, app.UIAxesCubeSat);
        end

        % Value changed function: SizeDropDownXplus
        function SizeDropDownXplusValueChanged(app, event)
            value = app.SizeDropDownXplus.Value;
            faceId = 1; % X+
            app.param.deployable.size(faceId) = value;

            plotCubesatView(app.param, app.UIAxesCubeSat);
        end

        % Value changed function: FlipPanelCheckBoxXplus
        function FlipPanelCheckBoxXplusValueChanged(app, event)
            value = app.FlipPanelCheckBoxXplus.Value;
            faceId = 1; % X+
            app.param.deployable.flipFixedPanel(faceId) = value;

            plotCubesatView(app.param, app.UIAxesCubeSat);
        end

        % Value changed function: CellEfficiencySpinnerXplusDeployable
        function CellEfficiencySpinnerXplusDeployableValueChanged(app, event)
            value = app.CellEfficiencySpinnerXplusDeployable.Value;
            faceId = 1; % X+
            app.param.deployable.solarCellEff(faceId) = value/100;
        end

        % Value changed function: EffecAreaRatioSpinnerXplusDeployable
        function EffecAreaRatioSpinnerXplusDeployableValueChanged(app, event)
            value = app.EffecAreaRatioSpinnerXplusDeployable.Value;
            faceId = 1; % X+
            app.param.deployable.effectiveAreaRatio(faceId) = value/100;
        end

        % Selection changed function: PanelTypeButtonGroupXminus
        function PanelTypeButtonGroupXminusSelectionChanged(app, event)
            selectedButton = app.PanelTypeButtonGroupXminus.SelectedObject;
            faceId = 2; % X-
            app.param.deployable.type(faceId) = selectedButton.Text;
            if( (selectedButton.Text == "Fixed") || (selectedButton.Text == "Tracking"))
                app.HingeDropDownXminus.Enable = "on";
                app.SizeDropDownXminus.Enable = "on";
                app.CellEfficiencySpinnerXminusDeployable.Enable = "on";
                app.EffecAreaRatioSpinnerXminusDeployable.Enable = "on";
                if(selectedButton.Text == "Fixed")
                    app.FlipPanelCheckBoxXminus.Enable = "on";
                else
                    app.FlipPanelCheckBoxXminus.Enable = "off";
                end

                plotCubesatView(app.param, app.UIAxesCubeSat);
            else
                app.HingeDropDownXminus.Enable = "off";
                app.SizeDropDownXminus.Enable = "off";
                app.CellEfficiencySpinnerXminusDeployable.Enable = "off";
                app.EffecAreaRatioSpinnerXminusDeployable.Enable = "off";
                app.FlipPanelCheckBoxXminus.Enable = "off";
                plotCubesatView(app.param, app.UIAxesCubeSat);
            end            
        end

        % Value changed function: HingeDropDownXminus
        function HingeDropDownXminusValueChanged(app, event)
            value = app.HingeDropDownXminus.Value;
            faceId = 2; % X-
            app.param.deployable.hinge(faceId) = value;

            plotCubesatView(app.param, app.UIAxesCubeSat);
        end

        % Value changed function: SizeDropDownXminus
        function SizeDropDownXminusValueChanged(app, event)
            value = app.SizeDropDownXminus.Value;
            faceId = 2; % X-
            app.param.deployable.size(faceId) = value;

            plotCubesatView(app.param, app.UIAxesCubeSat);           
        end

        % Value changed function: CellEfficiencySpinnerXminusDeployable
        function CellEfficiencySpinnerXminusDeployableValueChanged(app, event)
            value = app.CellEfficiencySpinnerXminusDeployable.Value;
            faceId = 2; % X-
            app.param.deployable.solarCellEff(faceId) = value/100;
        end

        % Value changed function: EffecAreaRatioSpinnerXminusDeployable
        function EffecAreaRatioSpinnerXminusDeployableValueChanged(app, event)
            value = app.EffecAreaRatioSpinnerXminusDeployable.Value;
            faceId = 2; % X-
            app.param.deployable.effectiveAreaRatio(faceId) = value/100;
        end

        % Value changed function: FlipPanelCheckBoxXminus
        function FlipPanelCheckBoxXminusValueChanged(app, event)
            value = app.FlipPanelCheckBoxXminus.Value;
            faceId = 2; % X-
            app.param.deployable.flipFixedPanel(faceId) = value;

            plotCubesatView(app.param, app.UIAxesCubeSat);
        end

        % Selection changed function: PanelTypeButtonGroupYplus
        function PanelTypeButtonGroupYplusSelectionChanged(app, event)
            selectedButton = app.PanelTypeButtonGroupYplus.SelectedObject;
            faceId = 3; % Y+
            app.param.deployable.type(faceId) = selectedButton.Text;
            if( (selectedButton.Text == "Fixed") || (selectedButton.Text == "Tracking"))
                app.HingeDropDownYplus.Enable = "on";
                app.SizeDropDownYplus.Enable = "on";
                app.CellEfficiencySpinnerYplusDeployable.Enable = "on";
                app.EffecAreaRatioSpinnerYplusDeployable.Enable = "on";
                if(selectedButton.Text == "Fixed")
                    app.FlipPanelCheckBoxYplus.Enable = "on";
                else
                    app.FlipPanelCheckBoxYplus.Enable = "off";
                end

                plotCubesatView(app.param, app.UIAxesCubeSat);
            else
                app.HingeDropDownYplus.Enable = "off";
                app.SizeDropDownYplus.Enable = "off";
                app.CellEfficiencySpinnerYplusDeployable.Enable = "off";
                app.EffecAreaRatioSpinnerYplusDeployable.Enable = "off";
                app.FlipPanelCheckBoxYplus.Enable = "off";
                plotCubesatView(app.param, app.UIAxesCubeSat);
            end
        end

        % Value changed function: HingeDropDownYplus
        function HingeDropDownYplusValueChanged(app, event)
            value = app.HingeDropDownYplus.Value;
            faceId = 3; % Y+
            app.param.deployable.hinge(faceId) = value;

            plotCubesatView(app.param, app.UIAxesCubeSat);
        end

        % Value changed function: SizeDropDownYplus
        function SizeDropDownYplusValueChanged(app, event)
            value = app.SizeDropDownYplus.Value;
            faceId = 3; % Y+
            app.param.deployable.size(faceId) = value;

            plotCubesatView(app.param, app.UIAxesCubeSat);            
        end

        % Value changed function: CellEfficiencySpinnerYplusDeployable
        function CellEfficiencySpinnerYplusDeployableValueChanged(app, event)
            value = app.CellEfficiencySpinnerYplusDeployable.Value;
            faceId = 3; % Y+
            app.param.deployable.solarCellEff(faceId) = value/100;            
        end

        % Value changed function: EffecAreaRatioSpinnerYplusDeployable
        function EffecAreaRatioSpinnerYplusDeployableValueChanged(app, event)
            value = app.EffecAreaRatioSpinnerYplusDeployable.Value;
            faceId = 3; % Y+
            app.param.deployable.effectiveAreaRatio(faceId) = value/100;
        end

        % Value changed function: FlipPanelCheckBoxYplus
        function FlipPanelCheckBoxYplusValueChanged(app, event)
            value = app.FlipPanelCheckBoxYplus.Value;
            faceId = 3; % Y+
            app.param.deployable.flipFixedPanel(faceId) = value;

            plotCubesatView(app.param, app.UIAxesCubeSat);
        end

        % Selection changed function: PanelTypeButtonGroupYminus
        function PanelTypeButtonGroupYminusSelectionChanged(app, event)
            selectedButton = app.PanelTypeButtonGroupYminus.SelectedObject;
            faceId = 4; % Y-
            app.param.deployable.type(faceId) = selectedButton.Text;
            if( (selectedButton.Text == "Fixed") || (selectedButton.Text == "Tracking"))
                app.HingeDropDownYminus.Enable = "on";
                app.SizeDropDownYminus.Enable = "on";
                app.CellEfficiencySpinnerYminusDeployable.Enable = "on";
                app.EffecAreaRatioSpinnerYminusDeployable.Enable = "on";
                if(selectedButton.Text == "Fixed")
                    app.FlipPanelCheckBoxYminus.Enable = "on";
                else
                    app.FlipPanelCheckBoxYminus.Enable = "off";
                end

                plotCubesatView(app.param, app.UIAxesCubeSat);
            else
                app.HingeDropDownYminus.Enable = "off";
                app.SizeDropDownYminus.Enable = "off";
                app.CellEfficiencySpinnerYminusDeployable.Enable = "off";
                app.EffecAreaRatioSpinnerYminusDeployable.Enable = "off";
                app.FlipPanelCheckBoxYminus.Enable = "off";
                plotCubesatView(app.param, app.UIAxesCubeSat);
            end  
        end

        % Value changed function: HingeDropDownYminus
        function HingeDropDownYminusValueChanged(app, event)
            value = app.HingeDropDownYminus.Value;
            faceId = 4; % Y-
            app.param.deployable.hinge(faceId) = value;

            plotCubesatView(app.param, app.UIAxesCubeSat);           
        end

        % Value changed function: SizeDropDownYminus
        function SizeDropDownYminusValueChanged(app, event)
            value = app.SizeDropDownYminus.Value;
            faceId = 4; % Y-
            app.param.deployable.size(faceId) = value;

            plotCubesatView(app.param, app.UIAxesCubeSat);             
        end

        % Value changed function: CellEfficiencySpinnerYminusDeployable
        function CellEfficiencySpinnerYminusDeployableValueChanged(app, event)
            value = app.CellEfficiencySpinnerYminusDeployable.Value;
            faceId = 4; % Y-
            app.param.deployable.solarCellEff(faceId) = value/100;               
        end

        % Value changed function: EffecAreaRatioSpinnerYminusDeployable
        function EffecAreaRatioSpinnerYminusDeployableValueChanged(app, event)
            value = app.EffecAreaRatioSpinnerYminusDeployable.Value;
            faceId = 4; % Y-
            app.param.deployable.effectiveAreaRatio(faceId) = value/100;            
        end

        % Value changed function: FlipPanelCheckBoxYminus
        function FlipPanelCheckBoxYminusValueChanged(app, event)
            value = app.FlipPanelCheckBoxYminus.Value;
            faceId = 4; % Y-
            app.param.deployable.flipFixedPanel(faceId) = value;

            plotCubesatView(app.param, app.UIAxesCubeSat);            
        end

        % Selection changed function: PanelTypeButtonGroupZplus
        function PanelTypeButtonGroupZplusSelectionChanged(app, event)
            selectedButton = app.PanelTypeButtonGroupZplus.SelectedObject;
            faceId = 5; % Z+
            app.param.deployable.type(faceId) = selectedButton.Text;
            if( (selectedButton.Text == "Fixed") || (selectedButton.Text == "Tracking"))
                app.HingeDropDownZplus.Enable = "on";
                app.SizeDropDownZplus.Enable = "on";
                app.CellEfficiencySpinnerZplusDeployable.Enable = "on";
                app.EffecAreaRatioSpinnerZplusDeployable.Enable = "on";
                if(selectedButton.Text == "Fixed")
                    app.FlipPanelCheckBoxZplus.Enable = "on";
                else
                    app.FlipPanelCheckBoxZplus.Enable = "off";
                end

                plotCubesatView(app.param, app.UIAxesCubeSat);
            else
                app.HingeDropDownZplus.Enable = "off";
                app.SizeDropDownZplus.Enable = "off";
                app.CellEfficiencySpinnerZplusDeployable.Enable = "off";
                app.EffecAreaRatioSpinnerZplusDeployable.Enable = "off";
                app.FlipPanelCheckBoxZplus.Enable = "off";
                plotCubesatView(app.param, app.UIAxesCubeSat);
            end            
        end

        % Value changed function: HingeDropDownZplus
        function HingeDropDownZplusValueChanged(app, event)
            value = app.HingeDropDownZplus.Value;
            faceId = 5; % Z+
            app.param.deployable.hinge(faceId) = value;

            plotCubesatView(app.param, app.UIAxesCubeSat);            
        end

        % Value changed function: SizeDropDownZplus
        function SizeDropDownZplusValueChanged(app, event)
            value = app.SizeDropDownZplus.Value;
            faceId = 5; % Z+
            app.param.deployable.size(faceId) = value;

            plotCubesatView(app.param, app.UIAxesCubeSat);             
        end

        % Value changed function: CellEfficiencySpinnerZplusDeployable
        function CellEfficiencySpinnerZplusDeployableValueChanged(app, event)
            value = app.CellEfficiencySpinnerZplusDeployable.Value;
            faceId = 5; % Z+
            app.param.deployable.solarCellEff(faceId) = value/100;             
        end

        % Value changed function: EffecAreaRatioSpinnerZplusDeployable
        function EffecAreaRatioSpinnerZplusDeployableValueChanged(app, event)
            value = app.EffecAreaRatioSpinnerZplusDeployable.Value;
            faceId = 5; % Z+
            app.param.deployable.effectiveAreaRatio(faceId) = value/100;             
        end

        % Value changed function: FlipPanelCheckBoxZplus
        function FlipPanelCheckBoxZplusValueChanged(app, event)
            value = app.FlipPanelCheckBoxZplus.Value;
            faceId = 5; % Z+
            app.param.deployable.flipFixedPanel(faceId) = value;

            plotCubesatView(app.param, app.UIAxesCubeSat);              
        end

        % Selection changed function: PanelTypeButtonGroupZminus
        function PanelTypeButtonGroupZminusSelectionChanged(app, event)
            selectedButton = app.PanelTypeButtonGroupZminus.SelectedObject;
            faceId = 6; % Z-
            app.param.deployable.type(faceId) = selectedButton.Text;
            if( (selectedButton.Text == "Fixed") || (selectedButton.Text == "Tracking"))
                app.HingeDropDownZminus.Enable = "on";
                app.SizeDropDownZminus.Enable = "on";
                app.CellEfficiencySpinnerZminusDeployable.Enable = "on";
                app.EffecAreaRatioSpinnerZminusDeployable.Enable = "on";
                if(selectedButton.Text == "Fixed")
                    app.FlipPanelCheckBoxZminus.Enable = "on";
                else
                    app.FlipPanelCheckBoxZminus.Enable = "off";
                end

                plotCubesatView(app.param, app.UIAxesCubeSat);
            else
                app.HingeDropDownZminus.Enable = "off";
                app.SizeDropDownZminus.Enable = "off";
                app.CellEfficiencySpinnerZminusDeployable.Enable = "off";
                app.EffecAreaRatioSpinnerZminusDeployable.Enable = "off";
                app.FlipPanelCheckBoxZminus.Enable = "off";
                plotCubesatView(app.param, app.UIAxesCubeSat);
            end              
        end

        % Value changed function: HingeDropDownZminus
        function HingeDropDownZminusValueChanged(app, event)
            value = app.HingeDropDownZminus.Value;
            faceId = 6; % Z-
            app.param.deployable.hinge(faceId) = value;

            plotCubesatView(app.param, app.UIAxesCubeSat);             
        end

        % Value changed function: SizeDropDownZminus
        function SizeDropDownZminusValueChanged(app, event)
            value = app.SizeDropDownZminus.Value;
            faceId = 6; % Z-
            app.param.deployable.size(faceId) = value;

            plotCubesatView(app.param, app.UIAxesCubeSat);            
        end

        % Value changed function: CellEfficiencySpinnerZminusDeployable
        function CellEfficiencySpinnerZminusDeployableValueChanged(app, event)
            value = app.CellEfficiencySpinnerZminusDeployable.Value;
            faceId = 6; % Z-
            app.param.deployable.solarCellEff(faceId) = value/100;               
        end

        % Value changed function: EffecAreaRatioSpinnerZminusDeployable
        function EffecAreaRatioSpinnerZminusDeployableValueChanged(app, event)
            value = app.EffecAreaRatioSpinnerZminusDeployable.Value;
            faceId = 6; % Z-
            app.param.deployable.effectiveAreaRatio(faceId) = value/100;           
        end

        % Value changed function: FlipPanelCheckBoxZminus
        function FlipPanelCheckBoxZminusValueChanged(app, event)
            value = app.FlipPanelCheckBoxZminus.Value;
            faceId = 6; % Z-
            app.param.deployable.flipFixedPanel(faceId) = value;

            plotCubesatView(app.param, app.UIAxesCubeSat);              
        end

        % Value changed function: FacesCheckBoxSolarRadiation
        function FacesCheckBoxSolarRadiationValueChanged(app, event)
            value = app.FacesCheckBoxSolarRadiation.Value;
            app.param.plot.solarRadiationInput.showFacesFlag = value;
            if(app.param.thermal.flag)
                plotSolarRadiationInput(app.UIAxesSolarRadiation, app.param.thermal, value)
            end
        end

        % Value changed function: FacesCheckBoxIRradiation
        function FacesCheckBoxIRradiationValueChanged(app, event)
            value = app.FacesCheckBoxIRradiation.Value;
            app.param.plot.IRRadiationInput.showFacesFlag = value;
            if(app.param.thermal.flag)
                plotIRRadiationInput(app.UIAxesIRinput, app.param.thermal, app.param.plot.IRRadiationInput.showFacesFlag, app.param.plot.IRRadiationInput.showAbsorbedIRFlag, app.param.plot.IRRadiationInput.showRadiatedIRFlag);
            end
        end

        % Value changed function: FacesCheckBoxAlbedoRadiation
        function FacesCheckBoxAlbedoRadiationValueChanged(app, event)
            value = app.FacesCheckBoxAlbedoRadiation.Value;
            app.param.plot.albedoRadiationInput.showFacesFlag = value;
            if(app.param.thermal.flag)
                plotAlbedoRadiationInput(app.UIAxesAlbedoInput, app.param.thermal, value)
            end
        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
            assignin('base', 'results', app.param)
        end

        % Value changed function: CheckBoxShowSunVector
        function CheckBoxShowSunVectorValueChanged(app, event)
            value = app.CheckBoxShowSunVector.Value;
            app.param.plot.orbitPlot.showSunVectorFlag = value;
            % Update plot if orbit was propagated
            if(app.param.orb.prop.flag)
                plotOrbit3D(app.UIAxesOrbit3D, app.param.orb.centralBody, app.param);
            end
        end

        % Callback function: OpenFolderButton
        function OpenFolderButtonClicked(app, event)
            [file,path] = uigetfile('*.mat');
            if (~isequal(file,0)) 
%                disp(['User selected ', fullfile(path,file)]);
               loadedData=load(fullfile(path,file));
               % Check if MAT file is compatible
               if(isfield(loadedData,'sim'))
                    inputCompatible = checkInputCompatibility(loadedData.sim);
               else
                   inputCompatible = 0;
               end
               % Load parameters and set app objects
               if(inputCompatible)
                   try
                       app.param = loadedData.sim;
                       setupAppObjects(app);
                   catch ME
                        errordlg('Invalid input file');
                   end
               end
                   
               
            end
        end

        % Callback function: SaveButton
        function SaveButtonClicked(app, event)
            [file,path] = uiputfile('*.mat','Save simulation parameters');
            if (~isequal(file,0)) 
                app.param.appVersion = "1.2.4";
                sim = app.param;
                save(fullfile(path,file),'sim')
            end
        end

        % Value changed function: IRplotOptionsDropDown
        function IRplotOptionsDropDownValueChanged(app, event)
            value = app.IRplotOptionsDropDown.Value;
            switch value
                case "Absorbed"
                    app.param.plot.IRRadiationInput.showAbsorbedIRFlag = 1;
                    app.param.plot.IRRadiationInput.showRadiatedIRFlag = 0;
                    app.FacesCheckBoxIRradiation.Enable = "on";
                case "Radiated"
                    app.param.plot.IRRadiationInput.showAbsorbedIRFlag = 0;
                    app.param.plot.IRRadiationInput.showRadiatedIRFlag = 1;
                    app.FacesCheckBoxIRradiation.Enable = "on";
                case "Both"
                    app.param.plot.IRRadiationInput.showAbsorbedIRFlag = 1;
                    app.param.plot.IRRadiationInput.showRadiatedIRFlag = 1;
                    app.param.plot.IRRadiationInput.showFacesFlag = 0;
                    app.FacesCheckBoxIRradiation.Value = 0;
                    app.FacesCheckBoxIRradiation.Enable = "off";
            end
            if(app.param.thermal.flag)
                plotIRRadiationInput(app.UIAxesIRinput, app.param.thermal, app.param.plot.IRRadiationInput.showFacesFlag, app.param.plot.IRRadiationInput.showAbsorbedIRFlag, app.param.plot.IRRadiationInput.showRadiatedIRFlag);
            end
        end

        % Value changed function: DayTempKEditField
        function DayTempKEditFieldValueChanged(app, event)
            value = app.DayTempKEditField.Value;
            app.param.Tir_Day = value;
        end

        % Value changed function: NightTempKEditField
        function NightTempKEditFieldValueChanged(app, event)
            value = app.NightTempKEditField.Value;
            app.param.Tir_Night = value;
        end

        % Value changed function: ConsidershadowsCheckBox
        function ConsidershadowsCheckBoxValueChanged(app, event)
            value = app.ConsidershadowsCheckBox.Value;
            app.param.computePwrWithShadowFlag = value;
        end

        % Value changing function: SliderTimeLine
        function SliderTimeLineValueChanging(app, event)
            changingValue = event.Value;
            plotAnimatedPwrCubeSat(app.param, app.UIAxesCubeSatPowerView, changingValue*3600)
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
%             value = app.PlayButton.Value;
            if(app.param.plot.animation.status == "stop" && app.param.pwr.flag)
                app.PlayButton.Icon = "pause.png";
                app.param.plot.animation.status = "play";
                t = app.SliderTimeLine.Value;
                % Get closest data point to user selected time
                sampleTime = app.param.pwr.time_Epoch(2) - app.param.pwr.time_Epoch(1);
                time = round(t*3600/sampleTime)*sampleTime;
                [i0, ~] = find(app.param.pwr.time_Epoch == time);
                indexArray = i0:length(app.param.pwr.time_Epoch);               
                timeArray = app.param.pwr.time_Epoch(indexArray);
                for i = 1:length(timeArray)
                    t = timeArray(i);
                    app.SliderTimeLine.Value = t/3600;
                    plotAnimatedPwrCubeSat(app.param, app.UIAxesCubeSatPowerView, t);
                    drawnow;
                    pause(0.05)
                    if(app.param.plot.animation.status == "stop")
                        break;
                    end
                    if(i == length(timeArray)) % Reach the end, go back to beginning and allow to play again
                        app.SliderTimeLine.Value = 0;
                        app.param.plot.animation.status = "stop";
                        app.PlayButton.Icon = "play.png";
                    end
                end

            else
                app.param.plot.animation.status = "stop";
                app.PlayButton.Icon = "play.png";
            end
        end

        % Callback function
        function PauseButtonValueChanged(app, event)
%             value = app.PauseButton.Value;
            if(app.param.plot.animation.status == "play")
                app.param.plot.animation.status = "stop";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CubeSatThermalPowerToolboxUIFigure = uifigure('Visible', 'off');
            app.CubeSatThermalPowerToolboxUIFigure.Name = 'CubeSat Thermal Power Toolbox';
            app.CubeSatThermalPowerToolboxUIFigure.Icon = fullfile(pathToMLAPP, 'AppIconCubeSat.png');
            app.Toolbar = uitoolbar(app.CubeSatThermalPowerToolboxUIFigure);
            app.SaveButton = uipushtool(app.Toolbar);
            app.SaveButton.ClickedCallback = createCallbackFcn(app, @SaveButtonClicked, true);
            app.SaveButton.Icon = fullfile(pathToMLAPP, 'Icons', 'Save.png');
            app.OpenFolderButton = uipushtool(app.Toolbar);
            app.OpenFolderButton.ClickedCallback = createCallbackFcn(app, @OpenFolderButtonClicked, true);
            app.OpenFolderButton.Icon = fullfile(pathToMLAPP, 'Icons', 'Folder.png');
            app.TabGroup2 = uitabgroup(app.CubeSatThermalPowerToolboxUIFigure);
            app.OrbitTab = uitab(app.TabGroup2);
            app.OrbitTab.Title = 'Orbit';
            app.UIAxesOrbit3D = uiaxes(app.OrbitTab);
            app.UIAxesOrbit3D.Visible = 'off';
            app.UIAxesSunLight = uiaxes(app.OrbitTab);
            title(app.UIAxesSunLight, 'Sun Light')
            xlabel(app.UIAxesSunLight, 'Epoch (hour)')
            ylabel(app.UIAxesSunLight, 'Flag')
            zlabel(app.UIAxesSunLight, 'Z')
            app.UIAxesAltitude = uiaxes(app.OrbitTab);
            title(app.UIAxesAltitude, 'Altitude ')
            xlabel(app.UIAxesAltitude, 'Epoch (hour)')
            ylabel(app.UIAxesAltitude, 'h (km)')
            zlabel(app.UIAxesAltitude, 'Z')
            app.UIAxesBetaAngle = uiaxes(app.OrbitTab);
            title(app.UIAxesBetaAngle, 'Beta Angle ')
            xlabel(app.UIAxesBetaAngle, 'Epoch (hour)')
            ylabel(app.UIAxesBetaAngle, 'Beta (°)')
            zlabel(app.UIAxesBetaAngle, 'Z')
            app.CentralBodyDropDownLabel = uilabel(app.OrbitTab);
            app.CentralBodyDropDownLabel.Text = 'Central Body';
            app.CentralBodyDropDown = uidropdown(app.OrbitTab);
            app.CentralBodyDropDown.ValueChangedFcn = createCallbackFcn(app, @CentralBodyDropDownValueChanged, true);
            app.EpochDatePickerLabel = uilabel(app.OrbitTab);
            app.EpochDatePickerLabel.Text = 'Epoch';
            app.EpochDatePicker = uidatepicker(app.OrbitTab);
            app.EpochDatePicker.ValueChangedFcn = createCallbackFcn(app, @EpochDatePickerValueChanged, true);
            app.HSpinnerLabel = uilabel(app.OrbitTab);
            app.HSpinnerLabel.Text = 'H';
            app.HSpinner = uispinner(app.OrbitTab);
            app.HSpinner.Limits = [0 23];
            app.HSpinner.ValueChangedFcn = createCallbackFcn(app, @HSpinnerValueChanged, true);
            app.MSpinnerLabel = uilabel(app.OrbitTab);
            app.MSpinnerLabel.Text = 'M';
            app.MSpinner = uispinner(app.OrbitTab);
            app.MSpinner.Limits = [0 59];
            app.MSpinner.ValueChangedFcn = createCallbackFcn(app, @MSpinnerValueChanged, true);
            app.DurationhoursSpinnerLabel = uilabel(app.OrbitTab);
            app.DurationhoursSpinnerLabel.Text = 'Duration (hours)';
            app.DurationhoursSpinner = uispinner(app.OrbitTab);
            app.DurationhoursSpinner.Limits = [0.1 2160];
            app.DurationhoursSpinner.ValueChangedFcn = createCallbackFcn(app, @DurationhoursSpinnerValueChanged, true);
            app.DurationhoursSpinner.Value = 1;
            app.DSpinnerLabel = uilabel(app.OrbitTab);
            app.DSpinnerLabel.Text = 'D';
            app.SemimajoraxiskmEditFieldLabel = uilabel(app.OrbitTab);
            app.SemimajoraxiskmEditFieldLabel.Text = 'Semi-major axis (km)';
            app.SemimajoraxiskmEditField = uieditfield(app.OrbitTab, 'numeric');
            app.SemimajoraxiskmEditField.Limits = [0 Inf];
            app.SemimajoraxiskmEditField.ValueChangedFcn = createCallbackFcn(app, @SemimajoraxiskmEditFieldValueChanged, true);
            app.EccentricityEditFieldLabel = uilabel(app.OrbitTab);
            app.EccentricityEditFieldLabel.Text = 'Eccentricity';
            app.EccentricityEditField = uieditfield(app.OrbitTab, 'numeric');
            app.EccentricityEditField.Limits = [0 1];
            app.EccentricityEditField.ValueChangedFcn = createCallbackFcn(app, @EccentricityEditFieldValueChanged, true);
            app.InclinationEditFieldLabel = uilabel(app.OrbitTab);
            app.InclinationEditFieldLabel.Text = 'Inclination';
            app.InclinationEditField = uieditfield(app.OrbitTab, 'numeric');
            app.InclinationEditField.Limits = [0 180];
            app.InclinationEditField.ValueChangedFcn = createCallbackFcn(app, @InclinationEditFieldValueChanged, true);
            app.RAANEditFieldLabel = uilabel(app.OrbitTab);
            app.RAANEditFieldLabel.Text = 'RAAN';
            app.RAANEditField = uieditfield(app.OrbitTab, 'numeric');
            app.RAANEditField.Limits = [0 360];
            app.RAANEditField.ValueChangedFcn = createCallbackFcn(app, @RAANEditFieldValueChanged, true);
            app.ArgumentofPeriapsisEditFieldLabel = uilabel(app.OrbitTab);
            app.ArgumentofPeriapsisEditFieldLabel.Text = 'Argument of Periapsis';
            app.ArgumentofPeriapsisEditField = uieditfield(app.OrbitTab, 'numeric');
            app.ArgumentofPeriapsisEditField.Limits = [0 360];
            app.ArgumentofPeriapsisEditField.ValueChangedFcn = createCallbackFcn(app, @ArgumentofPeriapsisEditFieldValueChanged, true);
            app.TrueAnomalyEditFieldLabel = uilabel(app.OrbitTab);
            app.TrueAnomalyEditFieldLabel.Text = 'True Anomaly';
            app.TrueAnomalyEditField = uieditfield(app.OrbitTab, 'numeric');
            app.TrueAnomalyEditField.Limits = [0 360];
            app.TrueAnomalyEditField.ValueChangedFcn = createCallbackFcn(app, @TrueAnomalyEditFieldValueChanged, true);
            app.SolarfluxWm2EditFieldLabel = uilabel(app.OrbitTab);
            app.SolarfluxWm2EditFieldLabel.Text = 'Solar flux (W/m2)';
            app.SolarfluxWm2EditField = uieditfield(app.OrbitTab, 'numeric');
            app.SolarfluxWm2EditField.Limits = [0 10000];
            app.SolarfluxWm2EditField.ValueChangedFcn = createCallbackFcn(app, @SolarfluxWm2EditFieldValueChanged, true);
            app.SolarfluxWm2EditField.Value = 1361;
            app.IRmodelDropDownLabel = uilabel(app.OrbitTab);
            app.IRmodelDropDownLabel.Text = 'IR model';
            app.IRmodelDropDown = uidropdown(app.OrbitTab);
            app.IRmodelDropDown.ValueChangedFcn = createCallbackFcn(app, @IRmodelDropDownValueChanged, true);
            app.DayTempKEditFieldLabel = uilabel(app.OrbitTab);
            app.DayTempKEditFieldLabel.Text = 'Day Temp (K)';
            app.DayTempKEditField = uieditfield(app.OrbitTab, 'numeric');
            app.DayTempKEditField.Limits = [0 1000];
            app.DayTempKEditField.ValueChangedFcn = createCallbackFcn(app, @DayTempKEditFieldValueChanged, true);
            app.NightTempKEditFieldLabel = uilabel(app.OrbitTab);
            app.NightTempKEditFieldLabel.Text = 'Night Temp (K)';
            app.NightTempKEditField = uieditfield(app.OrbitTab, 'numeric');
            app.NightTempKEditField.Limits = [0 1000];
            app.NightTempKEditField.ValueChangedFcn = createCallbackFcn(app, @NightTempKEditFieldValueChanged, true);
            app.AlbedoEditFieldLabel = uilabel(app.OrbitTab);
            app.AlbedoEditFieldLabel.Text = 'Albedo';
            app.AlbedoEditField = uieditfield(app.OrbitTab, 'numeric');
            app.AlbedoEditField.Limits = [0 1];
            app.PropagatorDropDownLabel = uilabel(app.OrbitTab);
            app.PropagatorDropDownLabel.Text = 'Propagator';
            app.PropagatorDropDown = uidropdown(app.OrbitTab);
            app.PropagatorDropDown.ValueChangedFcn = createCallbackFcn(app, @PropagatorDropDownValueChanged, true);
            app.CheckBoxShowSunVector = uicheckbox(app.OrbitTab);
            app.CheckBoxShowSunVector.ValueChangedFcn = createCallbackFcn(app, @CheckBoxShowSunVectorValueChanged, true);
            app.CheckBoxShowSunVector.Text = 'Sun Vector (t=0)';
            app.UpdateOrbitButton = uibutton(app.OrbitTab, 'push');
            app.UpdateOrbitButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateOrbitButtonPushed, true);
            app.UpdateOrbitButton.Text = 'Update Orbit';
            app.UpdateOrbitStatusLabel = uilabel(app.OrbitTab);
            app.UpdateOrbitStatusLabel.Text = 'Completed';
            app.ModelTab = uitab(app.TabGroup2);
            app.ModelTab.Title = 'Model';
            app.UIAxesCubeSat = uiaxes(app.ModelTab);
            xlabel(app.UIAxesCubeSat, 'X')
            ylabel(app.UIAxesCubeSat, 'Y')
            zlabel(app.UIAxesCubeSat, 'Z')
            app.ThermalParametersPanel = uipanel(app.ModelTab);
            app.ThermalParametersPanel.Title = 'Thermal Parameters';
            app.SpinnerRXplus = uispinner(app.ThermalParametersPanel);
            app.SpinnerRXplus.Limits = [0 1000];
            app.SpinnerRXplus.ValueChangedFcn = createCallbackFcn(app, @SpinnerRXplusValueChanged, true);
            app.SpinnerRXplus.Value = 1;
            app.RXLabel = uilabel(app.ThermalParametersPanel);
            app.RXLabel.Text = 'RX+';
            app.RXLabel_2 = uilabel(app.ThermalParametersPanel);
            app.RXLabel_2.Text = 'RX-';
            app.SpinnerRXminus = uispinner(app.ThermalParametersPanel);
            app.SpinnerRXminus.Limits = [0 1000];
            app.SpinnerRXminus.ValueChangedFcn = createCallbackFcn(app, @SpinnerRXminusValueChanged, true);
            app.SpinnerRXminus.Value = 1;
            app.RXLabel_3 = uilabel(app.ThermalParametersPanel);
            app.RXLabel_3.Text = 'RY+';
            app.SpinnerRYplus = uispinner(app.ThermalParametersPanel);
            app.SpinnerRYplus.Limits = [0 1000];
            app.SpinnerRYplus.ValueChangedFcn = createCallbackFcn(app, @SpinnerRYplusValueChanged, true);
            app.SpinnerRYplus.Value = 1;
            app.RXLabel_4 = uilabel(app.ThermalParametersPanel);
            app.RXLabel_4.Text = 'RY-';
            app.SpinnerRYminus = uispinner(app.ThermalParametersPanel);
            app.SpinnerRYminus.Limits = [0 1000];
            app.SpinnerRYminus.ValueChangedFcn = createCallbackFcn(app, @SpinnerRYminusValueChanged, true);
            app.SpinnerRYminus.Value = 1;
            app.RXLabel_5 = uilabel(app.ThermalParametersPanel);
            app.RXLabel_5.Text = 'RZ+';
            app.SpinnerRZplus = uispinner(app.ThermalParametersPanel);
            app.SpinnerRZplus.Limits = [0 1000];
            app.SpinnerRZplus.ValueChangedFcn = createCallbackFcn(app, @SpinnerRZplusValueChanged, true);
            app.SpinnerRZplus.Value = 1;
            app.RXLabel_6 = uilabel(app.ThermalParametersPanel);
            app.RXLabel_6.Text = 'RZ-';
            app.SpinnerRZminus = uispinner(app.ThermalParametersPanel);
            app.SpinnerRZminus.Limits = [0 1000];
            app.SpinnerRZminus.ValueChangedFcn = createCallbackFcn(app, @SpinnerRZminusValueChanged, true);
            app.SpinnerRZminus.Value = 1;
            app.ThermalResistanceImage = uiimage(app.ThermalParametersPanel);
            app.ThermalResistanceImage.ImageSource = fullfile(pathToMLAPP, 'Figures', 'ThermalModel7NodesSchematic_v2.png');
            app.IntermalnodetototalmassratioSpinnerLabel = uilabel(app.ThermalParametersPanel);
            app.IntermalnodetototalmassratioSpinnerLabel.Text = {'Intermal node'; 'to total mass '; 'ratio'};
            app.IntermalnodetototalmassratioSpinner = uispinner(app.ThermalParametersPanel);
            app.IntermalnodetototalmassratioSpinner.Step = 5;
            app.IntermalnodetototalmassratioSpinner.Limits = [0 95];
            app.IntermalnodetototalmassratioSpinner.ValueDisplayFormat = '%0.0f%%';
            app.IntermalnodetototalmassratioSpinner.ValueChangedFcn = createCallbackFcn(app, @IntermalnodetototalmassratioSpinnerValueChanged, true);
            app.IntermalnodetototalmassratioSpinner.Value = 70;
            app.IntermalnodeSpecificHeatJkgKSpinnerLabel = uilabel(app.ThermalParametersPanel);
            app.IntermalnodeSpecificHeatJkgKSpinnerLabel.Text = {'Intermal node'; 'Specific Heat'; '(J/kg/K)'};
            app.IntermalnodeSpecificHeatJkgKSpinner = uispinner(app.ThermalParametersPanel);
            app.IntermalnodeSpecificHeatJkgKSpinner.Step = 100;
            app.IntermalnodeSpecificHeatJkgKSpinner.Limits = [0 10000];
            app.IntermalnodeSpecificHeatJkgKSpinner.ValueChangedFcn = createCallbackFcn(app, @IntermalnodeSpecificHeatJkgKSpinnerValueChanged, true);
            app.IntermalnodeSpecificHeatJkgKSpinner.Value = 903;
            app.ThermalResistancesUnitKWLabel = uilabel(app.ThermalParametersPanel);
            app.ThermalResistancesUnitKWLabel.Text = 'Thermal Resistances (Unit: K/W)';
            app.UITableMassDistribution = uitable(app.ThermalParametersPanel);
            app.UITableMassDistribution.ColumnName = {'Node'; 'Mass (kg)'};
            app.UITableMassDistribution.RowName = {};
            app.UITableMassDistribution.Multiselect = 'off';
            app.TabGroup = uitabgroup(app.ModelTab);
            app.XplusTab = uitab(app.TabGroup);
            app.XplusTab.Title = 'X+';
            app.CellEfficiencySpinnerLabelXplus = uilabel(app.XplusTab);
            app.CellEfficiencySpinnerLabelXplus.Visible = 'off';
            app.CellEfficiencySpinnerLabelXplus.Text = 'Cell Efficiency';
            app.CellEfficiencySpinnerXplus = uispinner(app.XplusTab);
            app.CellEfficiencySpinnerXplus.Limits = [0 50];
            app.CellEfficiencySpinnerXplus.ValueDisplayFormat = '%0.0f%%';
            app.CellEfficiencySpinnerXplus.ValueChangedFcn = createCallbackFcn(app, @CellEfficiencySpinnerXplusValueChanged, true);
            app.CellEfficiencySpinnerXplus.Visible = 'off';
            app.CellEfficiencySpinnerXplus.Value = 28;
            app.EffecAreaRatioSpinnerLabelXplus = uilabel(app.XplusTab);
            app.EffecAreaRatioSpinnerLabelXplus.Visible = 'off';
            app.EffecAreaRatioSpinnerLabelXplus.Text = 'Packing Factor';
            app.EffecAreaRatioSpinnerXplus = uispinner(app.XplusTab);
            app.EffecAreaRatioSpinnerXplus.Limits = [0 100];
            app.EffecAreaRatioSpinnerXplus.ValueDisplayFormat = '%0.0f%%';
            app.EffecAreaRatioSpinnerXplus.ValueChangedFcn = createCallbackFcn(app, @EffecAreaRatioSpinnerXplusValueChanged, true);
            app.EffecAreaRatioSpinnerXplus.Visible = 'off';
            app.EffecAreaRatioSpinnerXplus.Value = 60;
            app.SurfaceTypeDropDownLabel = uilabel(app.XplusTab);
            app.SurfaceTypeDropDownLabel.Text = 'Surface Type';
            app.SurfaceTypeDropDownXplus = uidropdown(app.XplusTab);
            app.SurfaceTypeDropDownXplus.ValueChangedFcn = createCallbackFcn(app, @SurfaceTypeDropDownXplusValueChanged, true);
            app.AbsorptivityalphaLabel = uilabel(app.XplusTab);
            app.AbsorptivityalphaLabel.Enable = 'off';
            app.AbsorptivityalphaLabel.Text = 'Absorptivity α';
            app.AbsorptivitySpinnerXplus = uispinner(app.XplusTab);
            app.AbsorptivitySpinnerXplus.Step = 0.1;
            app.AbsorptivitySpinnerXplus.Limits = [0 1];
            app.AbsorptivitySpinnerXplus.ValueDisplayFormat = '%.2g';
            app.AbsorptivitySpinnerXplus.ValueChangedFcn = createCallbackFcn(app, @AbsorptivitySpinnerXplusValueChanged, true);
            app.AbsorptivitySpinnerXplus.Enable = 'off';
            app.EmissivityLabel = uilabel(app.XplusTab);
            app.EmissivityLabel.Enable = 'off';
            app.EmissivityLabel.Text = 'Emissivity ε';
            app.EmissivitySpinnerXplus = uispinner(app.XplusTab);
            app.EmissivitySpinnerXplus.Step = 0.1;
            app.EmissivitySpinnerXplus.Limits = [0 1];
            app.EmissivitySpinnerXplus.ValueDisplayFormat = '%.2g';
            app.EmissivitySpinnerXplus.ValueChangedFcn = createCallbackFcn(app, @EmissivitySpinnerXplusValueChanged, true);
            app.EmissivitySpinnerXplus.Enable = 'off';
            app.Label = uilabel(app.XplusTab);
            app.Label.Enable = 'off';
            app.Label.Text = 'Ratio α/ε';
            app.RatioEditFieldXplus = uieditfield(app.XplusTab, 'numeric');
            app.RatioEditFieldXplus.Limits = [0 Inf];
            app.RatioEditFieldXplus.ValueDisplayFormat = '%.2g';
            app.RatioEditFieldXplus.Editable = 'off';
            app.RatioEditFieldXplus.Enable = 'off';
            app.AbsorptivityalphaLabel_2 = uilabel(app.XplusTab);
            app.AbsorptivityalphaLabel_2.Enable = 'off';
            app.AbsorptivityalphaLabel_2.Text = 'Specific heat (J/kg/K)';
            app.CpSpinnerXplus = uispinner(app.XplusTab);
            app.CpSpinnerXplus.Step = 50;
            app.CpSpinnerXplus.Limits = [0 Inf];
            app.CpSpinnerXplus.ValueChangedFcn = createCallbackFcn(app, @CpSpinnerXplusValueChanged, true);
            app.CpSpinnerXplus.Enable = 'off';
            app.DeployableSolarPanelXplus = uipanel(app.XplusTab);
            app.DeployableSolarPanelXplus.Title = 'Deployable Solar Panel';
            app.PanelTypeButtonGroupXplus = uibuttongroup(app.DeployableSolarPanelXplus);
            app.PanelTypeButtonGroupXplus.SelectionChangedFcn = createCallbackFcn(app, @PanelTypeButtonGroupXplusSelectionChanged, true);
            app.NoneButtonXplus = uiradiobutton(app.PanelTypeButtonGroupXplus);
            app.NoneButtonXplus.Text = 'None';
            app.NoneButtonXplus.Value = true;
            app.FixedButtonXplus = uiradiobutton(app.PanelTypeButtonGroupXplus);
            app.FixedButtonXplus.Text = 'Fixed';
            app.TrackingButtonXplus = uiradiobutton(app.PanelTypeButtonGroupXplus);
            app.TrackingButtonXplus.Text = 'Tracking';
            app.HingeDropDownLabel = uilabel(app.DeployableSolarPanelXplus);
            app.HingeDropDownLabel.Text = 'Hinge';
            app.HingeDropDownXplus = uidropdown(app.DeployableSolarPanelXplus);
            app.HingeDropDownXplus.ValueChangedFcn = createCallbackFcn(app, @HingeDropDownXplusValueChanged, true);
            app.CellEfficiencySpinnerLabelXplusDeployable = uilabel(app.DeployableSolarPanelXplus);
            app.CellEfficiencySpinnerLabelXplusDeployable.Text = 'Cell Efficiency';
            app.CellEfficiencySpinnerXplusDeployable = uispinner(app.DeployableSolarPanelXplus);
            app.CellEfficiencySpinnerXplusDeployable.Limits = [0 50];
            app.CellEfficiencySpinnerXplusDeployable.ValueDisplayFormat = '%0.0f%%';
            app.CellEfficiencySpinnerXplusDeployable.ValueChangedFcn = createCallbackFcn(app, @CellEfficiencySpinnerXplusDeployableValueChanged, true);
            app.CellEfficiencySpinnerXplusDeployable.Value = 28;
            app.EffecAreaRatioSpinnerLabelXplus_2 = uilabel(app.DeployableSolarPanelXplus);
            app.EffecAreaRatioSpinnerLabelXplus_2.Text = 'Packing Factor';
            app.EffecAreaRatioSpinnerXplusDeployable = uispinner(app.DeployableSolarPanelXplus);
            app.EffecAreaRatioSpinnerXplusDeployable.Limits = [0 100];
            app.EffecAreaRatioSpinnerXplusDeployable.ValueDisplayFormat = '%0.0f%%';
            app.EffecAreaRatioSpinnerXplusDeployable.ValueChangedFcn = createCallbackFcn(app, @EffecAreaRatioSpinnerXplusDeployableValueChanged, true);
            app.EffecAreaRatioSpinnerXplusDeployable.Value = 60;
            app.SizeDropDownLabel = uilabel(app.DeployableSolarPanelXplus);
            app.SizeDropDownLabel.Text = 'Size';
            app.SizeDropDownXplus = uidropdown(app.DeployableSolarPanelXplus);
            app.SizeDropDownXplus.ValueChangedFcn = createCallbackFcn(app, @SizeDropDownXplusValueChanged, true);
            app.FlipPanelCheckBoxXplus = uicheckbox(app.DeployableSolarPanelXplus);
            app.FlipPanelCheckBoxXplus.ValueChangedFcn = createCallbackFcn(app, @FlipPanelCheckBoxXplusValueChanged, true);
            app.FlipPanelCheckBoxXplus.Text = 'Flip panel';
            app.XminusTab = uitab(app.TabGroup);
            app.XminusTab.Title = 'X-';
            app.CellEfficiencySpinnerLabelXminus = uilabel(app.XminusTab);
            app.CellEfficiencySpinnerLabelXminus.Visible = 'off';
            app.CellEfficiencySpinnerLabelXminus.Text = 'Cell Efficiency';
            app.CellEfficiencySpinnerXminus = uispinner(app.XminusTab);
            app.CellEfficiencySpinnerXminus.Limits = [0 50];
            app.CellEfficiencySpinnerXminus.ValueDisplayFormat = '%0.0f%%';
            app.CellEfficiencySpinnerXminus.ValueChangedFcn = createCallbackFcn(app, @CellEfficiencySpinnerXminusValueChanged, true);
            app.CellEfficiencySpinnerXminus.Visible = 'off';
            app.CellEfficiencySpinnerXminus.Value = 28;
            app.EffecAreaRatioSpinnerLabelXminus = uilabel(app.XminusTab);
            app.EffecAreaRatioSpinnerLabelXminus.Visible = 'off';
            app.EffecAreaRatioSpinnerLabelXminus.Text = 'Packing Factor';
            app.EffecAreaRatioSpinnerXminus = uispinner(app.XminusTab);
            app.EffecAreaRatioSpinnerXminus.Limits = [0 100];
            app.EffecAreaRatioSpinnerXminus.ValueDisplayFormat = '%0.0f%%';
            app.EffecAreaRatioSpinnerXminus.ValueChangedFcn = createCallbackFcn(app, @EffecAreaRatioSpinnerXminusValueChanged, true);
            app.EffecAreaRatioSpinnerXminus.Visible = 'off';
            app.EffecAreaRatioSpinnerXminus.Value = 60;
            app.Label_2 = uilabel(app.XminusTab);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = 'Ratio α/ε';
            app.RatioEditFieldXminus = uieditfield(app.XminusTab, 'numeric');
            app.RatioEditFieldXminus.Limits = [0 Inf];
            app.RatioEditFieldXminus.ValueDisplayFormat = '%.2g';
            app.RatioEditFieldXminus.Editable = 'off';
            app.RatioEditFieldXminus.Enable = 'off';
            app.EmissivityLabel_2 = uilabel(app.XminusTab);
            app.EmissivityLabel_2.Enable = 'off';
            app.EmissivityLabel_2.Text = 'Emissivity ε';
            app.EmissivitySpinnerXminus = uispinner(app.XminusTab);
            app.EmissivitySpinnerXminus.Step = 0.1;
            app.EmissivitySpinnerXminus.Limits = [0 1];
            app.EmissivitySpinnerXminus.ValueDisplayFormat = '%.2g';
            app.EmissivitySpinnerXminus.ValueChangedFcn = createCallbackFcn(app, @EmissivitySpinnerXminusValueChanged, true);
            app.EmissivitySpinnerXminus.Enable = 'off';
            app.AbsorptivityalphaLabel_3 = uilabel(app.XminusTab);
            app.AbsorptivityalphaLabel_3.Enable = 'off';
            app.AbsorptivityalphaLabel_3.Text = 'Absorptivity α';
            app.AbsorptivitySpinnerXminus = uispinner(app.XminusTab);
            app.AbsorptivitySpinnerXminus.Step = 0.1;
            app.AbsorptivitySpinnerXminus.Limits = [0 1];
            app.AbsorptivitySpinnerXminus.ValueDisplayFormat = '%.2g';
            app.AbsorptivitySpinnerXminus.ValueChangedFcn = createCallbackFcn(app, @AbsorptivitySpinnerXminusValueChanged, true);
            app.AbsorptivitySpinnerXminus.Enable = 'off';
            app.AbsorptivityalphaLabel_4 = uilabel(app.XminusTab);
            app.AbsorptivityalphaLabel_4.Enable = 'off';
            app.AbsorptivityalphaLabel_4.Text = 'Specific heat (J/kg/K)';
            app.CpSpinnerXminus = uispinner(app.XminusTab);
            app.CpSpinnerXminus.Step = 50;
            app.CpSpinnerXminus.Limits = [0 Inf];
            app.CpSpinnerXminus.ValueChangedFcn = createCallbackFcn(app, @CpSpinnerXminusValueChanged, true);
            app.CpSpinnerXminus.Enable = 'off';
            app.SurfaceTypeDropDownLabel_2 = uilabel(app.XminusTab);
            app.SurfaceTypeDropDownLabel_2.Text = 'Surface Type';
            app.SurfaceTypeDropDownXminus = uidropdown(app.XminusTab);
            app.SurfaceTypeDropDownXminus.ValueChangedFcn = createCallbackFcn(app, @SurfaceTypeDropDownXminusValueChanged, true);
            app.DeployableSolarPanelXminus = uipanel(app.XminusTab);
            app.DeployableSolarPanelXminus.Title = 'Deployable Solar Panel';
            app.PanelTypeButtonGroupXminus = uibuttongroup(app.DeployableSolarPanelXminus);
            app.PanelTypeButtonGroupXminus.SelectionChangedFcn = createCallbackFcn(app, @PanelTypeButtonGroupXminusSelectionChanged, true);
            app.NoneButtonXminus = uiradiobutton(app.PanelTypeButtonGroupXminus);
            app.NoneButtonXminus.Text = 'None';
            app.NoneButtonXminus.Value = true;
            app.FixedButtonXminus = uiradiobutton(app.PanelTypeButtonGroupXminus);
            app.FixedButtonXminus.Text = 'Fixed';
            app.TrackingButtonXminus = uiradiobutton(app.PanelTypeButtonGroupXminus);
            app.TrackingButtonXminus.Text = 'Tracking';
            app.HingeDropDownLabel_2 = uilabel(app.DeployableSolarPanelXminus);
            app.HingeDropDownLabel_2.Text = 'Hinge';
            app.HingeDropDownXminus = uidropdown(app.DeployableSolarPanelXminus);
            app.HingeDropDownXminus.ValueChangedFcn = createCallbackFcn(app, @HingeDropDownXminusValueChanged, true);
            app.CellEfficiencySpinnerLabelXminusDeployable = uilabel(app.DeployableSolarPanelXminus);
            app.CellEfficiencySpinnerLabelXminusDeployable.Text = 'Cell Efficiency';
            app.CellEfficiencySpinnerXminusDeployable = uispinner(app.DeployableSolarPanelXminus);
            app.CellEfficiencySpinnerXminusDeployable.Limits = [0 50];
            app.CellEfficiencySpinnerXminusDeployable.ValueDisplayFormat = '%0.0f%%';
            app.CellEfficiencySpinnerXminusDeployable.ValueChangedFcn = createCallbackFcn(app, @CellEfficiencySpinnerXminusDeployableValueChanged, true);
            app.CellEfficiencySpinnerXminusDeployable.Value = 28;
            app.EffecAreaRatioSpinnerLabelXplus_3 = uilabel(app.DeployableSolarPanelXminus);
            app.EffecAreaRatioSpinnerLabelXplus_3.Text = 'Packing Factor';
            app.EffecAreaRatioSpinnerXminusDeployable = uispinner(app.DeployableSolarPanelXminus);
            app.EffecAreaRatioSpinnerXminusDeployable.Limits = [0 100];
            app.EffecAreaRatioSpinnerXminusDeployable.ValueDisplayFormat = '%0.0f%%';
            app.EffecAreaRatioSpinnerXminusDeployable.ValueChangedFcn = createCallbackFcn(app, @EffecAreaRatioSpinnerXminusDeployableValueChanged, true);
            app.EffecAreaRatioSpinnerXminusDeployable.Value = 60;
            app.SizeDropDownLabelXminus = uilabel(app.DeployableSolarPanelXminus);
            app.SizeDropDownLabelXminus.Text = 'Size';
            app.SizeDropDownXminus = uidropdown(app.DeployableSolarPanelXminus);
            app.SizeDropDownXminus.ValueChangedFcn = createCallbackFcn(app, @SizeDropDownXminusValueChanged, true);
            app.FlipPanelCheckBoxXminus = uicheckbox(app.DeployableSolarPanelXminus);
            app.FlipPanelCheckBoxXminus.ValueChangedFcn = createCallbackFcn(app, @FlipPanelCheckBoxXminusValueChanged, true);
            app.FlipPanelCheckBoxXminus.Text = 'Flip panel';
            app.YplusTab = uitab(app.TabGroup);
            app.YplusTab.Title = 'Y+';
            app.CellEfficiencySpinnerLabelYplus = uilabel(app.YplusTab);
            app.CellEfficiencySpinnerLabelYplus.Visible = 'off';
            app.CellEfficiencySpinnerLabelYplus.Text = 'Cell Efficiency';
            app.CellEfficiencySpinnerYplus = uispinner(app.YplusTab);
            app.CellEfficiencySpinnerYplus.Limits = [0 50];
            app.CellEfficiencySpinnerYplus.ValueDisplayFormat = '%0.0f%%';
            app.CellEfficiencySpinnerYplus.ValueChangedFcn = createCallbackFcn(app, @CellEfficiencySpinnerYplusValueChanged, true);
            app.CellEfficiencySpinnerYplus.Visible = 'off';
            app.CellEfficiencySpinnerYplus.Value = 28;
            app.EffecAreaRatioSpinnerLabelYplus = uilabel(app.YplusTab);
            app.EffecAreaRatioSpinnerLabelYplus.Visible = 'off';
            app.EffecAreaRatioSpinnerLabelYplus.Text = 'Packing Factor';
            app.EffecAreaRatioSpinnerYplus = uispinner(app.YplusTab);
            app.EffecAreaRatioSpinnerYplus.Limits = [0 100];
            app.EffecAreaRatioSpinnerYplus.ValueDisplayFormat = '%0.0f%%';
            app.EffecAreaRatioSpinnerYplus.ValueChangedFcn = createCallbackFcn(app, @EffecAreaRatioSpinnerYplusValueChanged, true);
            app.EffecAreaRatioSpinnerYplus.Visible = 'off';
            app.EffecAreaRatioSpinnerYplus.Value = 60;
            app.Label_3 = uilabel(app.YplusTab);
            app.Label_3.Enable = 'off';
            app.Label_3.Text = 'Ratio α/ε';
            app.RatioEditFieldYplus = uieditfield(app.YplusTab, 'numeric');
            app.RatioEditFieldYplus.Limits = [0 Inf];
            app.RatioEditFieldYplus.ValueDisplayFormat = '%.2g';
            app.RatioEditFieldYplus.Editable = 'off';
            app.RatioEditFieldYplus.Enable = 'off';
            app.EmissivityLabel_3 = uilabel(app.YplusTab);
            app.EmissivityLabel_3.Enable = 'off';
            app.EmissivityLabel_3.Text = 'Emissivity ε';
            app.EmissivitySpinnerYplus = uispinner(app.YplusTab);
            app.EmissivitySpinnerYplus.Step = 0.1;
            app.EmissivitySpinnerYplus.Limits = [0 1];
            app.EmissivitySpinnerYplus.ValueDisplayFormat = '%.2g';
            app.EmissivitySpinnerYplus.ValueChangedFcn = createCallbackFcn(app, @EmissivitySpinnerYplusValueChanged, true);
            app.EmissivitySpinnerYplus.Enable = 'off';
            app.AbsorptivityalphaLabel_5 = uilabel(app.YplusTab);
            app.AbsorptivityalphaLabel_5.Enable = 'off';
            app.AbsorptivityalphaLabel_5.Text = 'Absorptivity α';
            app.AbsorptivitySpinnerYplus = uispinner(app.YplusTab);
            app.AbsorptivitySpinnerYplus.Step = 0.1;
            app.AbsorptivitySpinnerYplus.Limits = [0 1];
            app.AbsorptivitySpinnerYplus.ValueDisplayFormat = '%.2g';
            app.AbsorptivitySpinnerYplus.ValueChangedFcn = createCallbackFcn(app, @AbsorptivitySpinnerYplusValueChanged, true);
            app.AbsorptivitySpinnerYplus.Enable = 'off';
            app.AbsorptivityalphaLabel_6 = uilabel(app.YplusTab);
            app.AbsorptivityalphaLabel_6.Enable = 'off';
            app.AbsorptivityalphaLabel_6.Text = 'Specific heat (J/kg/K)';
            app.CpSpinnerYplus = uispinner(app.YplusTab);
            app.CpSpinnerYplus.Step = 50;
            app.CpSpinnerYplus.Limits = [0 Inf];
            app.CpSpinnerYplus.ValueChangedFcn = createCallbackFcn(app, @CpSpinnerYplusValueChanged, true);
            app.CpSpinnerYplus.Enable = 'off';
            app.SurfaceTypeDropDownLabel_3 = uilabel(app.YplusTab);
            app.SurfaceTypeDropDownLabel_3.Text = 'Surface Type';
            app.SurfaceTypeDropDownYplus = uidropdown(app.YplusTab);
            app.SurfaceTypeDropDownYplus.ValueChangedFcn = createCallbackFcn(app, @SurfaceTypeDropDownYplusValueChanged, true);
            app.DeployableSolarPanelYplus = uipanel(app.YplusTab);
            app.DeployableSolarPanelYplus.Title = 'Deployable Solar Panel';
            app.PanelTypeButtonGroupYplus = uibuttongroup(app.DeployableSolarPanelYplus);
            app.PanelTypeButtonGroupYplus.SelectionChangedFcn = createCallbackFcn(app, @PanelTypeButtonGroupYplusSelectionChanged, true);
            app.NoneButtonYplus = uiradiobutton(app.PanelTypeButtonGroupYplus);
            app.NoneButtonYplus.Text = 'None';
            app.NoneButtonYplus.Value = true;
            app.FixedButtonYplus = uiradiobutton(app.PanelTypeButtonGroupYplus);
            app.FixedButtonYplus.Text = 'Fixed';
            app.TrackingButtonYplus = uiradiobutton(app.PanelTypeButtonGroupYplus);
            app.TrackingButtonYplus.Text = 'Tracking';
            app.HingeDropDownLabel_3 = uilabel(app.DeployableSolarPanelYplus);
            app.HingeDropDownLabel_3.Text = 'Hinge';
            app.HingeDropDownYplus = uidropdown(app.DeployableSolarPanelYplus);
            app.HingeDropDownYplus.ValueChangedFcn = createCallbackFcn(app, @HingeDropDownYplusValueChanged, true);
            app.CellEfficiencySpinnerLabelYplusDeployable = uilabel(app.DeployableSolarPanelYplus);
            app.CellEfficiencySpinnerLabelYplusDeployable.Text = 'Cell Efficiency';
            app.CellEfficiencySpinnerYplusDeployable = uispinner(app.DeployableSolarPanelYplus);
            app.CellEfficiencySpinnerYplusDeployable.Limits = [0 50];
            app.CellEfficiencySpinnerYplusDeployable.ValueDisplayFormat = '%0.0f%%';
            app.CellEfficiencySpinnerYplusDeployable.ValueChangedFcn = createCallbackFcn(app, @CellEfficiencySpinnerYplusDeployableValueChanged, true);
            app.CellEfficiencySpinnerYplusDeployable.Value = 28;
            app.EffecAreaRatioSpinnerLabelXplus_4 = uilabel(app.DeployableSolarPanelYplus);
            app.EffecAreaRatioSpinnerLabelXplus_4.Text = 'Packing Factor';
            app.EffecAreaRatioSpinnerYplusDeployable = uispinner(app.DeployableSolarPanelYplus);
            app.EffecAreaRatioSpinnerYplusDeployable.Limits = [0 100];
            app.EffecAreaRatioSpinnerYplusDeployable.ValueDisplayFormat = '%0.0f%%';
            app.EffecAreaRatioSpinnerYplusDeployable.ValueChangedFcn = createCallbackFcn(app, @EffecAreaRatioSpinnerYplusDeployableValueChanged, true);
            app.EffecAreaRatioSpinnerYplusDeployable.Value = 60;
            app.SizeDropDownLabel_3 = uilabel(app.DeployableSolarPanelYplus);
            app.SizeDropDownLabel_3.Text = 'Size';
            app.SizeDropDownYplus = uidropdown(app.DeployableSolarPanelYplus);
            app.SizeDropDownYplus.ValueChangedFcn = createCallbackFcn(app, @SizeDropDownYplusValueChanged, true);
            app.FlipPanelCheckBoxYplus = uicheckbox(app.DeployableSolarPanelYplus);
            app.FlipPanelCheckBoxYplus.ValueChangedFcn = createCallbackFcn(app, @FlipPanelCheckBoxYplusValueChanged, true);
            app.FlipPanelCheckBoxYplus.Text = 'Flip panel';
            app.YminusTab = uitab(app.TabGroup);
            app.YminusTab.Title = 'Y-';
            app.CellEfficiencySpinnerLabelYminus = uilabel(app.YminusTab);
            app.CellEfficiencySpinnerLabelYminus.Visible = 'off';
            app.CellEfficiencySpinnerLabelYminus.Text = 'Cell Efficiency';
            app.CellEfficiencySpinnerYminus = uispinner(app.YminusTab);
            app.CellEfficiencySpinnerYminus.Limits = [0 50];
            app.CellEfficiencySpinnerYminus.ValueDisplayFormat = '%0.0f%%';
            app.CellEfficiencySpinnerYminus.ValueChangedFcn = createCallbackFcn(app, @CellEfficiencySpinnerYminusValueChanged, true);
            app.CellEfficiencySpinnerYminus.Visible = 'off';
            app.CellEfficiencySpinnerYminus.Value = 28;
            app.EffecAreaRatioSpinnerLabelYminus = uilabel(app.YminusTab);
            app.EffecAreaRatioSpinnerLabelYminus.Visible = 'off';
            app.EffecAreaRatioSpinnerLabelYminus.Text = 'Packing Factor';
            app.EffecAreaRatioSpinnerYminus = uispinner(app.YminusTab);
            app.EffecAreaRatioSpinnerYminus.Limits = [0 100];
            app.EffecAreaRatioSpinnerYminus.ValueDisplayFormat = '%0.0f%%';
            app.EffecAreaRatioSpinnerYminus.ValueChangedFcn = createCallbackFcn(app, @EffecAreaRatioSpinnerYminusValueChanged, true);
            app.EffecAreaRatioSpinnerYminus.Visible = 'off';
            app.EffecAreaRatioSpinnerYminus.Value = 60;
            app.Label_4 = uilabel(app.YminusTab);
            app.Label_4.Enable = 'off';
            app.Label_4.Text = 'Ratio α/ε';
            app.RatioEditFieldYminus = uieditfield(app.YminusTab, 'numeric');
            app.RatioEditFieldYminus.Limits = [0 Inf];
            app.RatioEditFieldYminus.ValueDisplayFormat = '%.2g';
            app.RatioEditFieldYminus.Editable = 'off';
            app.RatioEditFieldYminus.Enable = 'off';
            app.EmissivityLabel_4 = uilabel(app.YminusTab);
            app.EmissivityLabel_4.Enable = 'off';
            app.EmissivityLabel_4.Text = 'Emissivity ε';
            app.EmissivitySpinnerYminus = uispinner(app.YminusTab);
            app.EmissivitySpinnerYminus.Step = 0.1;
            app.EmissivitySpinnerYminus.Limits = [0 1];
            app.EmissivitySpinnerYminus.ValueDisplayFormat = '%.2g';
            app.EmissivitySpinnerYminus.ValueChangedFcn = createCallbackFcn(app, @EmissivitySpinnerYminusValueChanged, true);
            app.EmissivitySpinnerYminus.Enable = 'off';
            app.AbsorptivityalphaLabel_7 = uilabel(app.YminusTab);
            app.AbsorptivityalphaLabel_7.Enable = 'off';
            app.AbsorptivityalphaLabel_7.Text = 'Absorptivity α';
            app.AbsorptivitySpinnerYminus = uispinner(app.YminusTab);
            app.AbsorptivitySpinnerYminus.Step = 0.1;
            app.AbsorptivitySpinnerYminus.Limits = [0 1];
            app.AbsorptivitySpinnerYminus.ValueDisplayFormat = '%.2g';
            app.AbsorptivitySpinnerYminus.ValueChangedFcn = createCallbackFcn(app, @AbsorptivitySpinnerYminusValueChanged, true);
            app.AbsorptivitySpinnerYminus.Enable = 'off';
            app.AbsorptivityalphaLabel_8 = uilabel(app.YminusTab);
            app.AbsorptivityalphaLabel_8.Enable = 'off';
            app.AbsorptivityalphaLabel_8.Text = 'Specific heat (J/kg/K)';
            app.CpSpinnerYminus = uispinner(app.YminusTab);
            app.CpSpinnerYminus.Step = 50;
            app.CpSpinnerYminus.Limits = [0 Inf];
            app.CpSpinnerYminus.ValueChangedFcn = createCallbackFcn(app, @CpSpinnerYminusValueChanged, true);
            app.CpSpinnerYminus.Enable = 'off';
            app.SurfaceTypeDropDownLabel_4 = uilabel(app.YminusTab);
            app.SurfaceTypeDropDownLabel_4.Text = 'Surface Type';
            app.SurfaceTypeDropDownYminus = uidropdown(app.YminusTab);
            app.SurfaceTypeDropDownYminus.ValueChangedFcn = createCallbackFcn(app, @SurfaceTypeDropDownYminusValueChanged, true);
            app.DeployableSolarPanelYminus = uipanel(app.YminusTab);
            app.DeployableSolarPanelYminus.Title = 'Deployable Solar Panel';
            app.PanelTypeButtonGroupYminus = uibuttongroup(app.DeployableSolarPanelYminus);
            app.PanelTypeButtonGroupYminus.SelectionChangedFcn = createCallbackFcn(app, @PanelTypeButtonGroupYminusSelectionChanged, true);
            app.NoneButtonYminus = uiradiobutton(app.PanelTypeButtonGroupYminus);
            app.NoneButtonYminus.Text = 'None';
            app.NoneButtonYminus.Value = true;
            app.FixedButtonYminus = uiradiobutton(app.PanelTypeButtonGroupYminus);
            app.FixedButtonYminus.Text = 'Fixed';
            app.TrackingButtonYminus = uiradiobutton(app.PanelTypeButtonGroupYminus);
            app.TrackingButtonYminus.Text = 'Tracking';
            app.HingeDropDownLabel_4 = uilabel(app.DeployableSolarPanelYminus);
            app.HingeDropDownLabel_4.Text = 'Hinge';
            app.HingeDropDownYminus = uidropdown(app.DeployableSolarPanelYminus);
            app.HingeDropDownYminus.ValueChangedFcn = createCallbackFcn(app, @HingeDropDownYminusValueChanged, true);
            app.CellEfficiencySpinnerLabelYminusDeployable = uilabel(app.DeployableSolarPanelYminus);
            app.CellEfficiencySpinnerLabelYminusDeployable.Text = 'Cell Efficiency';
            app.CellEfficiencySpinnerYminusDeployable = uispinner(app.DeployableSolarPanelYminus);
            app.CellEfficiencySpinnerYminusDeployable.Limits = [0 50];
            app.CellEfficiencySpinnerYminusDeployable.ValueDisplayFormat = '%0.0f%%';
            app.CellEfficiencySpinnerYminusDeployable.ValueChangedFcn = createCallbackFcn(app, @CellEfficiencySpinnerYminusDeployableValueChanged, true);
            app.CellEfficiencySpinnerYminusDeployable.Value = 28;
            app.EffecAreaRatioSpinnerLabelXplus_5 = uilabel(app.DeployableSolarPanelYminus);
            app.EffecAreaRatioSpinnerLabelXplus_5.Text = 'Packing Factor';
            app.EffecAreaRatioSpinnerYminusDeployable = uispinner(app.DeployableSolarPanelYminus);
            app.EffecAreaRatioSpinnerYminusDeployable.Limits = [0 100];
            app.EffecAreaRatioSpinnerYminusDeployable.ValueDisplayFormat = '%0.0f%%';
            app.EffecAreaRatioSpinnerYminusDeployable.ValueChangedFcn = createCallbackFcn(app, @EffecAreaRatioSpinnerYminusDeployableValueChanged, true);
            app.EffecAreaRatioSpinnerYminusDeployable.Value = 60;
            app.SizeDropDownLabel_4 = uilabel(app.DeployableSolarPanelYminus);
            app.SizeDropDownLabel_4.Text = 'Size';
            app.SizeDropDownYminus = uidropdown(app.DeployableSolarPanelYminus);
            app.SizeDropDownYminus.ValueChangedFcn = createCallbackFcn(app, @SizeDropDownYminusValueChanged, true);
            app.FlipPanelCheckBoxYminus = uicheckbox(app.DeployableSolarPanelYminus);
            app.FlipPanelCheckBoxYminus.ValueChangedFcn = createCallbackFcn(app, @FlipPanelCheckBoxYminusValueChanged, true);
            app.FlipPanelCheckBoxYminus.Text = 'Flip panel';
            app.ZplusTab = uitab(app.TabGroup);
            app.ZplusTab.Title = 'Z+';
            app.CellEfficiencySpinnerLabelZplus = uilabel(app.ZplusTab);
            app.CellEfficiencySpinnerLabelZplus.Visible = 'off';
            app.CellEfficiencySpinnerLabelZplus.Text = 'Cell Efficiency';
            app.CellEfficiencySpinnerZplus = uispinner(app.ZplusTab);
            app.CellEfficiencySpinnerZplus.Limits = [0 50];
            app.CellEfficiencySpinnerZplus.ValueDisplayFormat = '%0.0f%%';
            app.CellEfficiencySpinnerZplus.ValueChangedFcn = createCallbackFcn(app, @CellEfficiencySpinnerZplusValueChanged, true);
            app.CellEfficiencySpinnerZplus.Visible = 'off';
            app.CellEfficiencySpinnerZplus.Value = 28;
            app.EffecAreaRatioSpinnerLabelZplus = uilabel(app.ZplusTab);
            app.EffecAreaRatioSpinnerLabelZplus.Visible = 'off';
            app.EffecAreaRatioSpinnerLabelZplus.Text = 'Packing Factor';
            app.EffecAreaRatioSpinnerZplus = uispinner(app.ZplusTab);
            app.EffecAreaRatioSpinnerZplus.Limits = [0 100];
            app.EffecAreaRatioSpinnerZplus.ValueDisplayFormat = '%0.0f%%';
            app.EffecAreaRatioSpinnerZplus.ValueChangedFcn = createCallbackFcn(app, @EffecAreaRatioSpinnerZplusValueChanged, true);
            app.EffecAreaRatioSpinnerZplus.Visible = 'off';
            app.EffecAreaRatioSpinnerZplus.Value = 60;
            app.Label_5 = uilabel(app.ZplusTab);
            app.Label_5.Enable = 'off';
            app.Label_5.Text = 'Ratio α/ε';
            app.RatioEditFieldZplus = uieditfield(app.ZplusTab, 'numeric');
            app.RatioEditFieldZplus.Limits = [0 Inf];
            app.RatioEditFieldZplus.ValueDisplayFormat = '%.2g';
            app.RatioEditFieldZplus.Editable = 'off';
            app.RatioEditFieldZplus.Enable = 'off';
            app.EmissivityLabel_5 = uilabel(app.ZplusTab);
            app.EmissivityLabel_5.Enable = 'off';
            app.EmissivityLabel_5.Text = 'Emissivity ε';
            app.EmissivitySpinnerZplus = uispinner(app.ZplusTab);
            app.EmissivitySpinnerZplus.Step = 0.1;
            app.EmissivitySpinnerZplus.Limits = [0 1];
            app.EmissivitySpinnerZplus.ValueDisplayFormat = '%.2g';
            app.EmissivitySpinnerZplus.ValueChangedFcn = createCallbackFcn(app, @EmissivitySpinnerZplusValueChanged, true);
            app.EmissivitySpinnerZplus.Enable = 'off';
            app.AbsorptivityalphaLabel_9 = uilabel(app.ZplusTab);
            app.AbsorptivityalphaLabel_9.Enable = 'off';
            app.AbsorptivityalphaLabel_9.Text = 'Absorptivity α';
            app.AbsorptivitySpinnerZplus = uispinner(app.ZplusTab);
            app.AbsorptivitySpinnerZplus.Step = 0.1;
            app.AbsorptivitySpinnerZplus.Limits = [0 1];
            app.AbsorptivitySpinnerZplus.ValueDisplayFormat = '%.2g';
            app.AbsorptivitySpinnerZplus.ValueChangedFcn = createCallbackFcn(app, @AbsorptivitySpinnerZplusValueChanged, true);
            app.AbsorptivitySpinnerZplus.Enable = 'off';
            app.AbsorptivityalphaLabel_10 = uilabel(app.ZplusTab);
            app.AbsorptivityalphaLabel_10.Enable = 'off';
            app.AbsorptivityalphaLabel_10.Text = 'Specific heat (J/kg/K)';
            app.CpSpinnerZplus = uispinner(app.ZplusTab);
            app.CpSpinnerZplus.Step = 50;
            app.CpSpinnerZplus.Limits = [0 Inf];
            app.CpSpinnerZplus.ValueChangedFcn = createCallbackFcn(app, @CpSpinnerZplusValueChanged, true);
            app.CpSpinnerZplus.Enable = 'off';
            app.SurfaceTypeDropDownLabel_5 = uilabel(app.ZplusTab);
            app.SurfaceTypeDropDownLabel_5.Text = 'Surface Type';
            app.SurfaceTypeDropDownZplus = uidropdown(app.ZplusTab);
            app.SurfaceTypeDropDownZplus.ValueChangedFcn = createCallbackFcn(app, @SurfaceTypeDropDownZplusValueChanged, true);
            app.DeployableSolarPanelZplus = uipanel(app.ZplusTab);
            app.DeployableSolarPanelZplus.Title = 'Deployable Solar Panel';
            app.PanelTypeButtonGroupZplus = uibuttongroup(app.DeployableSolarPanelZplus);
            app.PanelTypeButtonGroupZplus.SelectionChangedFcn = createCallbackFcn(app, @PanelTypeButtonGroupZplusSelectionChanged, true);
            app.NoneButtonZplus = uiradiobutton(app.PanelTypeButtonGroupZplus);
            app.NoneButtonZplus.Text = 'None';
            app.NoneButtonZplus.Value = true;
            app.FixedButtonZplus = uiradiobutton(app.PanelTypeButtonGroupZplus);
            app.FixedButtonZplus.Text = 'Fixed';
            app.TrackingButtonZplus = uiradiobutton(app.PanelTypeButtonGroupZplus);
            app.TrackingButtonZplus.Text = 'Tracking';
            app.HingeDropDownLabelZplus2 = uilabel(app.DeployableSolarPanelZplus);
            app.HingeDropDownLabelZplus2.Text = 'Hinge';
            app.HingeDropDownZplus = uidropdown(app.DeployableSolarPanelZplus);
            app.HingeDropDownZplus.ValueChangedFcn = createCallbackFcn(app, @HingeDropDownZplusValueChanged, true);
            app.CellEfficiencySpinnerLabelZplusDeployable = uilabel(app.DeployableSolarPanelZplus);
            app.CellEfficiencySpinnerLabelZplusDeployable.Text = 'Cell Efficiency';
            app.CellEfficiencySpinnerZplusDeployable = uispinner(app.DeployableSolarPanelZplus);
            app.CellEfficiencySpinnerZplusDeployable.Limits = [0 50];
            app.CellEfficiencySpinnerZplusDeployable.ValueDisplayFormat = '%0.0f%%';
            app.CellEfficiencySpinnerZplusDeployable.ValueChangedFcn = createCallbackFcn(app, @CellEfficiencySpinnerZplusDeployableValueChanged, true);
            app.CellEfficiencySpinnerZplusDeployable.Value = 28;
            app.EffecAreaRatioSpinnerLabelZplus2 = uilabel(app.DeployableSolarPanelZplus);
            app.EffecAreaRatioSpinnerLabelZplus2.Text = 'Packing Factor';
            app.EffecAreaRatioSpinnerZplusDeployable = uispinner(app.DeployableSolarPanelZplus);
            app.EffecAreaRatioSpinnerZplusDeployable.Limits = [0 100];
            app.EffecAreaRatioSpinnerZplusDeployable.ValueDisplayFormat = '%0.0f%%';
            app.EffecAreaRatioSpinnerZplusDeployable.ValueChangedFcn = createCallbackFcn(app, @EffecAreaRatioSpinnerZplusDeployableValueChanged, true);
            app.EffecAreaRatioSpinnerZplusDeployable.Value = 60;
            app.SizeDropDownLabel_5 = uilabel(app.DeployableSolarPanelZplus);
            app.SizeDropDownLabel_5.Text = 'Size';
            app.SizeDropDownZplus = uidropdown(app.DeployableSolarPanelZplus);
            app.SizeDropDownZplus.ValueChangedFcn = createCallbackFcn(app, @SizeDropDownZplusValueChanged, true);
            app.FlipPanelCheckBoxZplus = uicheckbox(app.DeployableSolarPanelZplus);
            app.FlipPanelCheckBoxZplus.ValueChangedFcn = createCallbackFcn(app, @FlipPanelCheckBoxZplusValueChanged, true);
            app.FlipPanelCheckBoxZplus.Text = 'Flip panel';
            app.ZminusTab = uitab(app.TabGroup);
            app.ZminusTab.Title = 'Z-';
            app.CellEfficiencySpinnerLabelZminus = uilabel(app.ZminusTab);
            app.CellEfficiencySpinnerLabelZminus.Visible = 'off';
            app.CellEfficiencySpinnerLabelZminus.Text = 'Cell Efficiency';
            app.CellEfficiencySpinnerZminus = uispinner(app.ZminusTab);
            app.CellEfficiencySpinnerZminus.Limits = [0 50];
            app.CellEfficiencySpinnerZminus.ValueDisplayFormat = '%0.0f%%';
            app.CellEfficiencySpinnerZminus.ValueChangedFcn = createCallbackFcn(app, @CellEfficiencySpinnerZminusValueChanged, true);
            app.CellEfficiencySpinnerZminus.Visible = 'off';
            app.CellEfficiencySpinnerZminus.Value = 28;
            app.EffecAreaRatioSpinnerLabelZminus = uilabel(app.ZminusTab);
            app.EffecAreaRatioSpinnerLabelZminus.Visible = 'off';
            app.EffecAreaRatioSpinnerLabelZminus.Text = 'Packing Factor';
            app.EffecAreaRatioSpinnerZminus = uispinner(app.ZminusTab);
            app.EffecAreaRatioSpinnerZminus.Limits = [0 100];
            app.EffecAreaRatioSpinnerZminus.ValueDisplayFormat = '%0.0f%%';
            app.EffecAreaRatioSpinnerZminus.ValueChangedFcn = createCallbackFcn(app, @EffecAreaRatioSpinnerZminusValueChanged, true);
            app.EffecAreaRatioSpinnerZminus.Visible = 'off';
            app.EffecAreaRatioSpinnerZminus.Value = 60;
            app.Label_6 = uilabel(app.ZminusTab);
            app.Label_6.Enable = 'off';
            app.Label_6.Text = 'Ratio α/ε';
            app.RatioEditFieldZminus = uieditfield(app.ZminusTab, 'numeric');
            app.RatioEditFieldZminus.Limits = [0 Inf];
            app.RatioEditFieldZminus.ValueDisplayFormat = '%.2g';
            app.RatioEditFieldZminus.Editable = 'off';
            app.RatioEditFieldZminus.Enable = 'off';
            app.EmissivityLabel_6 = uilabel(app.ZminusTab);
            app.EmissivityLabel_6.Enable = 'off';
            app.EmissivityLabel_6.Text = 'Emissivity ε';
            app.EmissivitySpinnerZminus = uispinner(app.ZminusTab);
            app.EmissivitySpinnerZminus.Step = 0.1;
            app.EmissivitySpinnerZminus.Limits = [0 1];
            app.EmissivitySpinnerZminus.ValueDisplayFormat = '%.2g';
            app.EmissivitySpinnerZminus.ValueChangedFcn = createCallbackFcn(app, @EmissivitySpinnerZminusValueChanged, true);
            app.EmissivitySpinnerZminus.Enable = 'off';
            app.AbsorptivityalphaLabel_11 = uilabel(app.ZminusTab);
            app.AbsorptivityalphaLabel_11.Enable = 'off';
            app.AbsorptivityalphaLabel_11.Text = 'Absorptivity α';
            app.AbsorptivitySpinnerZminus = uispinner(app.ZminusTab);
            app.AbsorptivitySpinnerZminus.Step = 0.1;
            app.AbsorptivitySpinnerZminus.Limits = [0 1];
            app.AbsorptivitySpinnerZminus.ValueDisplayFormat = '%.2g';
            app.AbsorptivitySpinnerZminus.ValueChangedFcn = createCallbackFcn(app, @AbsorptivitySpinnerZminusValueChanged, true);
            app.AbsorptivitySpinnerZminus.Enable = 'off';
            app.AbsorptivityalphaLabel_12 = uilabel(app.ZminusTab);
            app.AbsorptivityalphaLabel_12.Enable = 'off';
            app.AbsorptivityalphaLabel_12.Text = 'Specific heat (J/kg/K)';
            app.CpSpinnerZminus = uispinner(app.ZminusTab);
            app.CpSpinnerZminus.Step = 50;
            app.CpSpinnerZminus.Limits = [0 Inf];
            app.CpSpinnerZminus.ValueChangedFcn = createCallbackFcn(app, @CpSpinnerZminusValueChanged, true);
            app.CpSpinnerZminus.Enable = 'off';
            app.SurfaceTypeDropDownLabel_6 = uilabel(app.ZminusTab);
            app.SurfaceTypeDropDownLabel_6.Text = 'Surface Type';
            app.SurfaceTypeDropDownZminus = uidropdown(app.ZminusTab);
            app.SurfaceTypeDropDownZminus.ValueChangedFcn = createCallbackFcn(app, @SurfaceTypeDropDownZminusValueChanged, true);
            app.DeployableSolarPanelZminus = uipanel(app.ZminusTab);
            app.DeployableSolarPanelZminus.Title = 'Deployable Solar Panel';
            app.PanelTypeButtonGroupZminus = uibuttongroup(app.DeployableSolarPanelZminus);
            app.PanelTypeButtonGroupZminus.SelectionChangedFcn = createCallbackFcn(app, @PanelTypeButtonGroupZminusSelectionChanged, true);
            app.NoneButtonZminus = uiradiobutton(app.PanelTypeButtonGroupZminus);
            app.NoneButtonZminus.Text = 'None';
            app.NoneButtonZminus.Value = true;
            app.FixedButtonZminus = uiradiobutton(app.PanelTypeButtonGroupZminus);
            app.FixedButtonZminus.Text = 'Fixed';
            app.TrackingButtonZminus = uiradiobutton(app.PanelTypeButtonGroupZminus);
            app.TrackingButtonZminus.Text = 'Tracking';
            app.HingeDropDownLabelZminus2 = uilabel(app.DeployableSolarPanelZminus);
            app.HingeDropDownLabelZminus2.Text = 'Hinge';
            app.HingeDropDownZminus = uidropdown(app.DeployableSolarPanelZminus);
            app.HingeDropDownZminus.ValueChangedFcn = createCallbackFcn(app, @HingeDropDownZminusValueChanged, true);
            app.CellEfficiencySpinnerLabelZminusDeployable = uilabel(app.DeployableSolarPanelZminus);
            app.CellEfficiencySpinnerLabelZminusDeployable.Text = 'Cell Efficiency';
            app.CellEfficiencySpinnerZminusDeployable = uispinner(app.DeployableSolarPanelZminus);
            app.CellEfficiencySpinnerZminusDeployable.Limits = [0 50];
            app.CellEfficiencySpinnerZminusDeployable.ValueDisplayFormat = '%0.0f%%';
            app.CellEfficiencySpinnerZminusDeployable.ValueChangedFcn = createCallbackFcn(app, @CellEfficiencySpinnerZminusDeployableValueChanged, true);
            app.CellEfficiencySpinnerZminusDeployable.Value = 28;
            app.EffecAreaRatioSpinnerLabelZminus2 = uilabel(app.DeployableSolarPanelZminus);
            app.EffecAreaRatioSpinnerLabelZminus2.Text = 'Packing Factor';
            app.EffecAreaRatioSpinnerZminusDeployable = uispinner(app.DeployableSolarPanelZminus);
            app.EffecAreaRatioSpinnerZminusDeployable.Limits = [0 100];
            app.EffecAreaRatioSpinnerZminusDeployable.ValueDisplayFormat = '%0.0f%%';
            app.EffecAreaRatioSpinnerZminusDeployable.ValueChangedFcn = createCallbackFcn(app, @EffecAreaRatioSpinnerZminusDeployableValueChanged, true);
            app.EffecAreaRatioSpinnerZminusDeployable.Value = 60;
            app.SizeDropDownLabelZminus2 = uilabel(app.DeployableSolarPanelZminus);
            app.SizeDropDownLabelZminus2.Text = 'Size';
            app.SizeDropDownZminus = uidropdown(app.DeployableSolarPanelZminus);
            app.SizeDropDownZminus.ValueChangedFcn = createCallbackFcn(app, @SizeDropDownZminusValueChanged, true);
            app.FlipPanelCheckBoxZminus = uicheckbox(app.DeployableSolarPanelZminus);
            app.FlipPanelCheckBoxZminus.ValueChangedFcn = createCallbackFcn(app, @FlipPanelCheckBoxZminusValueChanged, true);
            app.FlipPanelCheckBoxZminus.Text = 'Flip panel';
            app.UnitsDropDownLabel = uilabel(app.ModelTab);
            app.UnitsDropDownLabel.Text = '#Units';
            app.UnitsDropDown = uidropdown(app.ModelTab);
            app.UnitsDropDown.Items = {'1U', '3U', '6U', '8U', '12U', '16U'};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = '1U';
            app.BodyMasskgLabel = uilabel(app.ModelTab);
            app.BodyMasskgLabel.Text = 'Body Mass (kg)';
            app.BodyMasskgSpinner = uispinner(app.ModelTab);
            app.BodyMasskgSpinner.Limits = [0.5 30];
            app.BodyMasskgSpinner.ValueChangedFcn = createCallbackFcn(app, @BodyMasskgSpinnerValueChanged, true);
            app.BodyMasskgSpinner.Tooltip = {'Exclude deployable panels mass'};
            app.BodyMasskgSpinner.Value = 1;
            app.NadirFacegreenLabel = uilabel(app.ModelTab);
            app.NadirFacegreenLabel.Text = 'Nadir Face';
            app.NadirFaceDropDown = uidropdown(app.ModelTab);
            app.NadirFaceDropDown.ValueChangedFcn = createCallbackFcn(app, @NadirFaceDropDownValueChanged, true);
            app.RamFaceDropDownLabel = uilabel(app.ModelTab);
            app.RamFaceDropDownLabel.Text = 'Ram Face';
            app.RamFaceDropDown = uidropdown(app.ModelTab);
            app.RamFaceDropDown.ValueChangedFcn = createCallbackFcn(app, @RamFaceDropDownValueChanged, true);
            app.PowerTab = uitab(app.TabGroup2);
            app.PowerTab.Title = 'Power';
            app.UIAxesPwrConsumption = uiaxes(app.PowerTab);
            title(app.UIAxesPwrConsumption, 'Power Consumption ')
            xlabel(app.UIAxesPwrConsumption, 'Epoch (hour)')
            zlabel(app.UIAxesPwrConsumption, 'Z')
            app.UIAxesPwrGeneration = uiaxes(app.PowerTab);
            title(app.UIAxesPwrGeneration, 'Power Generation')
            xlabel(app.UIAxesPwrGeneration, 'Epoch (hour)')
            zlabel(app.UIAxesPwrGeneration, 'Z')
            app.UIAxesBatteryDepletion = uiaxes(app.PowerTab);
            title(app.UIAxesBatteryDepletion, 'Battery Depletion')
            xlabel(app.UIAxesBatteryDepletion, 'Epoch (hour)')
            zlabel(app.UIAxesBatteryDepletion, 'Z')
            app.UIAxesCubeSatPowerView = uiaxes(app.PowerTab);
            xlabel(app.UIAxesCubeSatPowerView, 'X')
            ylabel(app.UIAxesCubeSatPowerView, 'Y')
            zlabel(app.UIAxesCubeSatPowerView, 'Z')
            app.TabGroup3 = uitabgroup(app.PowerTab);
            app.Node1Tab = uitab(app.TabGroup3);
            app.Node1Tab.Title = 'Node 1';
            app.PwrDayWSpinnerLabel = uilabel(app.Node1Tab);
            app.PwrDayWSpinnerLabel.Text = 'Pwr Day (W)';
            app.PwrDayWSpinner = uispinner(app.Node1Tab);
            app.PwrDayWSpinner.Limits = [0 1000];
            app.PwrDayWSpinner.ValueChangedFcn = createCallbackFcn(app, @PwrDayWSpinnerValueChanged, true);
            app.ProfileDropDownLabel = uilabel(app.Node1Tab);
            app.ProfileDropDownLabel.Text = 'Profile';
            app.ProfileDropDown = uidropdown(app.Node1Tab);
            app.ProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @ProfileDropDownValueChanged, true);
            app.PwrNightWSpinnerLabel = uilabel(app.Node1Tab);
            app.PwrNightWSpinnerLabel.Text = 'Pwr Night (W)';
            app.PwrNightWSpinner = uispinner(app.Node1Tab);
            app.PwrNightWSpinner.Limits = [0 1000];
            app.PwrNightWSpinner.ValueChangedFcn = createCallbackFcn(app, @PwrNightWSpinnerValueChanged, true);
            app.RefreshButton = uibutton(app.Node1Tab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.InternalPowerDissipationLabel = uilabel(app.PowerTab);
            app.InternalPowerDissipationLabel.Text = 'Internal Power Dissipation';
            app.ComputePowerButton = uibutton(app.PowerTab, 'push');
            app.ComputePowerButton.ButtonPushedFcn = createCallbackFcn(app, @ComputePowerButtonPushed, true);
            app.ComputePowerButton.Text = 'Compute Power';
            app.ComputePowerStatusLabel = uilabel(app.PowerTab);
            app.ComputePowerStatusLabel.Text = 'Completed';
            app.TimeLineLabel = uilabel(app.PowerTab);
            app.TimeLineLabel.Text = 'Time Line';
            app.SliderTimeLine = uislider(app.PowerTab);
            app.SliderTimeLine.Limits = [0 60];
            app.SliderTimeLine.ValueChangingFcn = createCallbackFcn(app, @SliderTimeLineValueChanging, true);
            app.ConsidershadowsCheckBox = uicheckbox(app.PowerTab);
            app.ConsidershadowsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConsidershadowsCheckBoxValueChanged, true);
            app.ConsidershadowsCheckBox.Tooltip = {'Compute the power considering shadows between deployables and body.'};
            app.ConsidershadowsCheckBox.Text = 'Consider shadows';
            app.PlayButton = uibutton(app.PowerTab, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'Icons', 'play.png');
            app.PlayButton.Text = '';
            app.ThermalTab = uitab(app.TabGroup2);
            app.ThermalTab.Title = 'Thermal';
            app.UIAxesInternalNodesTemp = uiaxes(app.ThermalTab);
            title(app.UIAxesInternalNodesTemp, 'Internal Temperature')
            xlabel(app.UIAxesInternalNodesTemp, 'X')
            ylabel(app.UIAxesInternalNodesTemp, 'Y')
            zlabel(app.UIAxesInternalNodesTemp, 'Z')
            app.UIAxesSolarRadiation = uiaxes(app.ThermalTab);
            title(app.UIAxesSolarRadiation, 'Solar Radiation (Absorbed)')
            xlabel(app.UIAxesSolarRadiation, 'X')
            ylabel(app.UIAxesSolarRadiation, 'Y')
            zlabel(app.UIAxesSolarRadiation, 'Z')
            app.UIAxesIRinput = uiaxes(app.ThermalTab);
            title(app.UIAxesIRinput, 'IR Radiation')
            xlabel(app.UIAxesIRinput, 'X')
            ylabel(app.UIAxesIRinput, 'Y')
            zlabel(app.UIAxesIRinput, 'Z')
            app.UIAxesAlbedoInput = uiaxes(app.ThermalTab);
            title(app.UIAxesAlbedoInput, 'Albedo Radiation (Absorbed)')
            xlabel(app.UIAxesAlbedoInput, 'X')
            ylabel(app.UIAxesAlbedoInput, 'Y')
            zlabel(app.UIAxesAlbedoInput, 'Z')
            app.UIAxesFacesTemp = uiaxes(app.ThermalTab);
            title(app.UIAxesFacesTemp, 'Faces Temperature')
            xlabel(app.UIAxesFacesTemp, 'X')
            ylabel(app.UIAxesFacesTemp, 'Y')
            zlabel(app.UIAxesFacesTemp, 'Z')
            app.ComputeThermalButton = uibutton(app.ThermalTab, 'push');
            app.ComputeThermalButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeThermalButtonPushed, true);
            app.ComputeThermalButton.Text = 'Compute Thermal';
            app.ComputeThermalStatusLabel = uilabel(app.ThermalTab);
            app.ComputeThermalStatusLabel.Text = 'Completed';
            app.FacesCheckBoxSolarRadiation = uicheckbox(app.ThermalTab);
            app.FacesCheckBoxSolarRadiation.ValueChangedFcn = createCallbackFcn(app, @FacesCheckBoxSolarRadiationValueChanged, true);
            app.FacesCheckBoxSolarRadiation.Text = 'Faces';
            app.FacesCheckBoxIRradiation = uicheckbox(app.ThermalTab);
            app.FacesCheckBoxIRradiation.ValueChangedFcn = createCallbackFcn(app, @FacesCheckBoxIRradiationValueChanged, true);
            app.FacesCheckBoxIRradiation.Text = 'Faces';
            app.FacesCheckBoxAlbedoRadiation = uicheckbox(app.ThermalTab);
            app.FacesCheckBoxAlbedoRadiation.ValueChangedFcn = createCallbackFcn(app, @FacesCheckBoxAlbedoRadiationValueChanged, true);
            app.FacesCheckBoxAlbedoRadiation.Text = 'Faces';
            app.ExportResultsButton = uibutton(app.ThermalTab, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Tooltip = {'Export results to Workspace'};
            app.ExportResultsButton.Text = 'Export Results';
            app.IRplotOptionsDropDown = uidropdown(app.ThermalTab);
            app.IRplotOptionsDropDown.Items = {'Absorbed', 'Radiated', 'Both'};
            app.IRplotOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @IRplotOptionsDropDownValueChanged, true);
            app.IRplotOptionsDropDown.Value = 'Absorbed';
            app.ContextMenu = uicontextmenu(app.CubeSatThermalPowerToolboxUIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.CubeSatThermalPowerToolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CubeSat

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CubeSatThermalPowerToolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CubeSatThermalPowerToolboxUIFigure)
        end
    end
end